classdef CalcularEmissividade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalcularemissividadeUIFigure    matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TemperaturanopontoEditFieldLabel  matlab.ui.control.Label
        TemperaMedidaEditField          matlab.ui.control.NumericEditField
        TemperaturarealEditFieldLabel   matlab.ui.control.Label
        TemperaturarealEditField        matlab.ui.control.NumericEditField
        EmissividadeCalculadaEditFieldLabel  matlab.ui.control.Label
        EmissividadeCalculadaEditField  matlab.ui.control.NumericEditField
        AplicarButton                   matlab.ui.control.Button
        CancelarButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp
        ThermalCamera
        TemperaturaReal = 0;
        TemperaturaMedida = 0;
        Emissividade
    end

    methods (Access = public)
    
        function SetTemperatura(app, Temperatura)
            app.TemperaturaMedida = Temperatura;
            app.TemperaMedidaEditField.Value = Temperatura;
            app.TemperaturarealEditFieldValueChanged();
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, ThermalCamera)
            app.MainApp = MainApp;
            app.ThermalCamera = ThermalCamera;
        end

        % Value changed function: TemperaturarealEditField
        function TemperaturarealEditFieldValueChanged(app, event)
            value = app.TemperaturarealEditField.Value;
            app.TemperaturaReal = value;
            app.Emissividade = app.ThermalCamera.ThermalImage.GetEmissivityFromValue(app.TemperaturaMedida, app.TemperaturaReal);
            app.EmissividadeCalculadaEditField.Value = app.Emissividade;
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            if(app.EmissividadeCalculadaEditField.Value ~= -1)
                app.MainApp.SetEmissividade(app.Emissividade);
            end
            delete(app);
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalcularemissividadeUIFigure = uifigure;
            app.CalcularemissividadeUIFigure.Name = 'Calcular emissividade';
            app.Label = uilabel(app.CalcularemissividadeUIFigure);
            app.Label.Text = {'Selecione um ponto na imagem que a temperatura real é conhecida e '; 'informe a temperatura. '; 'Selecione aplicar para aplicar a emissividade na imagem térmica.'; '-1 indica erro no cálculo'};
            app.TemperaturanopontoEditFieldLabel = uilabel(app.CalcularemissividadeUIFigure);
            app.TemperaturanopontoEditFieldLabel.Text = 'Temperatura no ponto';
            app.TemperaMedidaEditField = uieditfield(app.CalcularemissividadeUIFigure, 'numeric');
            app.TemperaMedidaEditField.Editable = 'off';
            app.TemperaturarealEditFieldLabel = uilabel(app.CalcularemissividadeUIFigure);
            app.TemperaturarealEditFieldLabel.Text = 'Temperatura real';
            app.TemperaturarealEditField = uieditfield(app.CalcularemissividadeUIFigure, 'numeric');
            app.TemperaturarealEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturarealEditFieldValueChanged, true);
            app.EmissividadeCalculadaEditFieldLabel = uilabel(app.CalcularemissividadeUIFigure);
            app.EmissividadeCalculadaEditFieldLabel.Text = 'Emissividade Calculada';
            app.EmissividadeCalculadaEditField = uieditfield(app.CalcularemissividadeUIFigure, 'numeric');
            app.EmissividadeCalculadaEditField.Editable = 'off';
            app.AplicarButton = uibutton(app.CalcularemissividadeUIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.CancelarButton = uibutton(app.CalcularemissividadeUIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CalcularEmissividade(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalcularemissividadeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalcularemissividadeUIFigure)
        end
    end
end