classdef CameraTermica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArquivoMenu                     matlab.ui.container.Menu
        AbrirMenu                       matlab.ui.container.Menu
        SairMenu                        matlab.ui.container.Menu
        CmeraMenu                       matlab.ui.container.Menu
        ProcurarCmerasMenu              matlab.ui.container.Menu
        DesconectarcmeraMenu            matlab.ui.container.Menu
        OpesMenu                        matlab.ui.container.Menu
        ParmetrosTermicosMenu           matlab.ui.container.Menu
        DistanciaMenu                   matlab.ui.container.Menu
        EmissividadeMenu                matlab.ui.container.Menu
        TemperaturaAtmosfericaMenu      matlab.ui.container.Menu
        TemperaturaRefletidaMenu        matlab.ui.container.Menu
        TemperaturaOticaExternaMenu     matlab.ui.container.Menu
        TransmissaoOticaExternaMenu     matlab.ui.container.Menu
        TemperaturaDeReferenciaMenu     matlab.ui.container.Menu
        UmidadeRelativaMenu             matlab.ui.container.Menu
        TransmissaoAtmosfericaMenu      matlab.ui.container.Menu
        CalcularEmissividadeMenu        matlab.ui.container.Menu
        UnidadesMenu                    matlab.ui.container.Menu
        TamanhodoarquivoMenu            matlab.ui.container.Menu
        BMenu                           matlab.ui.container.Menu
        KBMenu                          matlab.ui.container.Menu
        MBMenu                          matlab.ui.container.Menu
        GBMenu                          matlab.ui.container.Menu
        TemperaturaMenu                 matlab.ui.container.Menu
        CelsiusMenu                     matlab.ui.container.Menu
        KelvinMenu                      matlab.ui.container.Menu
        FahrenheitMenu                  matlab.ui.container.Menu
        VisualizaoMenu                  matlab.ui.container.Menu
        TemperaturaMenu_2               matlab.ui.container.Menu
        SinalMenu                       matlab.ui.container.Menu
        EqdeHistogramaMenu              matlab.ui.container.Menu
        AtualizarhistogramaMenu         matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GravaoReproduoTab               matlab.ui.container.Tab
        GravaoPanel                     matlab.ui.container.Panel
        IniciargravaoButton             matlab.ui.control.Button
        ParargravaoButton               matlab.ui.control.Button
        FPSdisponveisDropDownLabel      matlab.ui.control.Label
        FPSdisponveisDropDown           matlab.ui.control.DropDown
        ResoluesDisponveisDropDownLabel  matlab.ui.control.Label
        ResoluesDisponveisDropDown      matlab.ui.control.DropDown
        ReproduoPanel                   matlab.ui.container.Panel
        RepetirCheckBox                 matlab.ui.control.CheckBox
        BotaoParar                      matlab.ui.control.Button
        FrameLabel                      matlab.ui.control.Label
        FrameEditField                  matlab.ui.control.NumericEditField
        SalvarImagemButton              matlab.ui.control.Button
        BotaoPlayPause                  matlab.ui.control.Button
        FPSLabel                        matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        SalvarPanel                     matlab.ui.container.Panel
        SalvarvdeoemLabel               matlab.ui.control.Label
        SalvarVideoEditField            matlab.ui.control.EditField
        SalvarImagememLabel             matlab.ui.control.Label
        SalvarImagemEditField           matlab.ui.control.EditField
        BotaoMenuVideo                  matlab.ui.control.Button
        BotaoMenuImagem                 matlab.ui.control.Button
        AparnciaPanel                   matlab.ui.container.Panel
        PaletadecoresLabel              matlab.ui.control.Label
        PaletadecoresDropDown           matlab.ui.control.DropDown
        SelecionarButton                matlab.ui.control.Button
        RegiodeajusteCheckBox           matlab.ui.control.CheckBox
        AnliseTab                       matlab.ui.container.Tab
        IsotermasPanel                  matlab.ui.container.Panel
        AdicionarAcimaButton            matlab.ui.control.Button
        AdicionarAbaixoButton           matlab.ui.control.Button
        RemoverButton                   matlab.ui.control.Button
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        AdicionarIntervaloButton        matlab.ui.control.Button
        AparnciaDropDownLabel           matlab.ui.control.Label
        AparnciaDropDown                matlab.ui.control.DropDown
        CordecontrasteDropDownLabel     matlab.ui.control.Label
        CordecontrasteDropDown          matlab.ui.control.DropDown
        TemperaturaPanel                matlab.ui.container.Panel
        CoordenadaXEditFieldLabel       matlab.ui.control.Label
        CoordenadaXEditField            matlab.ui.control.NumericEditField
        MostrarTemperaturaCheckBox      matlab.ui.control.CheckBox
        CoordenadaYEditFieldLabel       matlab.ui.control.Label
        CoordenadaYEditField            matlab.ui.control.NumericEditField
        TemperaturaEditFieldLabel       matlab.ui.control.Label
        TemperaturaEditField            matlab.ui.control.NumericEditField
        PlotarButton                    matlab.ui.control.Button
        nPontosLabel                    matlab.ui.control.Label
        nPontosEditField                matlab.ui.control.NumericEditField
        CopiarpontosButton              matlab.ui.control.Button
        EstatsticasPanel                matlab.ui.container.Panel
        TemperaturaMximaEditFieldLabel  matlab.ui.control.Label
        TemperaturaMaximaEditField      matlab.ui.control.NumericEditField
        TemperaturaMnimaEditFieldLabel  matlab.ui.control.Label
        TemperaturaMinimaEditField      matlab.ui.control.NumericEditField
        TemperaturaMdiaEditFieldLabel   matlab.ui.control.Label
        TemperaturaMediaEditField       matlab.ui.control.NumericEditField
        DesvioPadroEditFieldLabel       matlab.ui.control.Label
        DesvioPadroEditField            matlab.ui.control.NumericEditField
        CheckBoxTemperaturaMedia        matlab.ui.control.CheckBox
        CheckBoxTemperaturaMinima       matlab.ui.control.CheckBox
        CheckBoxTemperaturaMaxima       matlab.ui.control.CheckBox
        CheckBoxTemperaturaDesvio       matlab.ui.control.CheckBox
        PlotarLabel                     matlab.ui.control.Label
        VisualizarCheckBox              matlab.ui.control.CheckBox
        PlotarpropriedadesButton        matlab.ui.control.Button
        LinhasPanel                     matlab.ui.container.Panel
        LinhasCheckBox                  matlab.ui.control.CheckBox
        NumerodepontosEditFieldLabel    matlab.ui.control.Label
        NumerodepontosEditField         matlab.ui.control.NumericEditField
        HistogramaPanel                 matlab.ui.container.Panel
        HistogramaCheckBox              matlab.ui.control.CheckBox
        CompararPanel                   matlab.ui.container.Panel
        CompararpontosCheckBox          matlab.ui.control.CheckBox
        AplicarButton                   matlab.ui.control.Button
        DividirCheckBox                 matlab.ui.control.CheckBox
        FiltrosTab                      matlab.ui.container.Tab
        MedianaPanel                    matlab.ui.container.Panel
        TamanhodamscaraEditFieldLabel   matlab.ui.control.Label
        TamanhoMascaraMediana           matlab.ui.control.NumericEditField
        BotaoMediana                    matlab.ui.control.Button
        GaussianoPanel                  matlab.ui.container.Panel
        desviopadroEditFieldLabel       matlab.ui.control.Label
        DesvioPadraoEditField           matlab.ui.control.NumericEditField
        BotaoGauss                      matlab.ui.control.Button
        TamanhodamscaraEditFieldLabel_2  matlab.ui.control.Label
        TamanhoMascaraGauss             matlab.ui.control.NumericEditField
        BordasPanel                     matlab.ui.container.Panel
        BotaoBordas                     matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltrosatuaisListBoxLabel       matlab.ui.control.Label
        FiltrosatuaisListBox            matlab.ui.control.ListBox
        ErosoPanel                      matlab.ui.container.Panel
        BotaoErosao                     matlab.ui.control.Button
        ElementoestruturanteDropDownLabel  matlab.ui.control.Label
        ElementoErosaoDropDown          matlab.ui.control.DropDown
        DilataoPanel                    matlab.ui.container.Panel
        BotaoDilatacao                  matlab.ui.control.Button
        ElementoestruturanteDropDown_2Label  matlab.ui.control.Label
        ElementoDilatacaoDropDown       matlab.ui.control.DropDown
        BotaoMoverPraCima               matlab.ui.control.Button
        BotaoMoverPraBaixo              matlab.ui.control.Button
        BotaoDeletarFiltro              matlab.ui.control.Button
        EqualizaodohistogramaPanel      matlab.ui.container.Panel
        BotaoEqualizarHistograma        matlab.ui.control.Button
        CorrecaoGama                    matlab.ui.container.Panel
        ValordogammaLabel               matlab.ui.control.Label
        ValorGamma                      matlab.ui.control.NumericEditField
        BotaoGamma                      matlab.ui.control.Button
        AplicarVideoCheckBox            matlab.ui.control.CheckBox
        AplicarReconstrucaoCheckBox     matlab.ui.control.CheckBox
        LimiarizaoPanel                 matlab.ui.container.Panel
        AdicionarLimiar                 matlab.ui.control.Button
        AdicionarIntervalo              matlab.ui.control.Button
        AdicionarAutomatico             matlab.ui.control.Button
        TSRTab                          matlab.ui.container.Tab
        GerarMatrizButton               matlab.ui.control.Button
        UtilizeomtodolentoseovdeoLabel  matlab.ui.control.Label
        TamanhooriginalEditFieldLabel   matlab.ui.control.Label
        TamanhooriginalEditField        matlab.ui.control.NumericEditField
        TamanhodamatrizEditFieldLabel   matlab.ui.control.Label
        TamanhodamatrizEditField        matlab.ui.control.NumericEditField
        CompactaodeEditFieldLabel       matlab.ui.control.Label
        CompactacaoEditField            matlab.ui.control.NumericEditField
        SalvarmatrizemEditFieldLabel    matlab.ui.control.Label
        SalvarmatrizEditField           matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        MBLabel                         matlab.ui.control.Label
        MBLabel_2                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MenorrEditFieldLabel            matlab.ui.control.Label
        MenorrEditField                 matlab.ui.control.NumericEditField
        MaiorrEditFieldLabel            matlab.ui.control.Label
        MaiorrEditField                 matlab.ui.control.NumericEditField
        VisualizarMatrizButton          matlab.ui.control.Button
        PolinomioLabel2                 matlab.ui.control.Label
        PolinomioLabel                  matlab.ui.control.Label
        GraudopolinmioEditFieldLabel    matlab.ui.control.Label
        GrauPolinomioEditField          matlab.ui.control.NumericEditField
        FunodeajusteLabel               matlab.ui.control.Label
        FuncaodeajusteDropDown          matlab.ui.control.DropDown
        DividirLentoCheckBox            matlab.ui.control.CheckBox
        VisualizarreconstruoButton      matlab.ui.control.Button
        DerivarButton                   matlab.ui.control.Button
        TempoEditFieldLabel             matlab.ui.control.Label
        TempoEditField                  matlab.ui.control.NumericEditField
        CarregarmatrizButton            matlab.ui.control.Button
        DerivadaAtualLabel              matlab.ui.control.Label
        MatrizLabel                     matlab.ui.control.Label
        MatrizDropDown                  matlab.ui.control.DropDown
        FiltrarCheckBox                 matlab.ui.control.CheckBox
        TamanhodofiltroEditFieldLabel   matlab.ui.control.Label
        TamanhodofiltroEditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        TamanhoArquivo %Thermal Image File
        Seq %Thermal Sequence Player
        AxesImagem
        CameraLive
        CamerasDisponiveis
        IndexCameraSelecionada = 0;
        ThermalCamera
        RecordingPath
        ImagePath
        Paletas
        PaletaSelecionada
        BoolMudarPaleta
        Imagem
        BoolCameraConectada = false;
        XCoord
        YCoord
        spot
        Ponto
        Distance
        Emissivity
        ReflectedTemperature
        PlotarApp
        BoolPlotar = false;
        EnumIsotermas
        FigureImagem
        FigurePlot
        Pontos = cell(1,1);
        LinhasAnimadas
        PontoSelecionado = 1;
        MatrizPontos;
        PlotPontos % Description
        BoolPlotando = false; % Description
        NumeroPontos
        PlotEstatisticas % Description
        TextoEstatisticas
        PlotTempMaxima
        PlotTempMinima
        NomeMatriz = 'Matriz';
        PastaMatriz = strcat(getenv('userprofile'), '\Documents\'); % Caminho da matriz no disco
        NomeImagem = 'Imagem';
        PastaImagem = strcat(getenv('userprofile'), '\Documents\');
        NomeVideo = 'Video';
        PastaVideo = strcat(getenv('userprofile'), '\Documents\');
        rquadrado % Valor do r^2 dos polinômios de ajuste
        Coeficientes % Coeficientes dos polinômios de ajuste
        MatrizTempo %Matriz do tempo da temperatura máxima de cada píxel
        Grau % Grau dos polinômios de ajuste
        Unidade = '°C'; % Unidade da temperatura
        BoolCalcularEmissividade = false % Verdadeiro se estiver calculando emissividade
        TipoCamera % Description
        EmissividadeSpot % Ponto para calcular emissividade
        CalcularEmissividadeApp % Aplicativo para calcular a emissividade
        Linhas % Description
        PlotLinhas % Description
        PontosLinhas % Description
        fps = 0; % Description
        V % Description
        AxesPlots % Description
        FigureReconstrucao % Description
        AxesReconstrucao % Description
        FuncaoReconst % Description
        Frame  % Description
        PontosRetangulo % Description
        FiguraSelecionada % Description
        RetanguloHistograma % Description
        AxesPlot % Description
        ImagemReconstrucao % Description
        BoolAplicarFiltros = false; % Description
        CaminhoDerivada % Description
        NumeroMatriz % Description
        TamanhoMatriz % Description
        RetanguloRegiao % Description
        MatrizIndMax % Description
    end

    methods (Access = public)
        
        function SetIndexCamera(app, IndexCameraSelecionada)
            app.IndexCameraSelecionada = IndexCameraSelecionada;
        end
    
        function setCamera(app)
            app.DesconectarcmeraMenu.Enable = 'on';
            app.BoolCameraConectada = true;
            app.CameraLive = app.IndexCameraSelecionada;
            app.TipoCamera = app.CamerasDisponiveis.Item(app.IndexCameraSelecionada-1).SelectedStreamingFormat;
            if(strcmp(app.TipoCamera, 'FlirFileFormat'))
                app.MostrarTemperaturaCheckBox.Enable = 'on';
                app.IniciargravaoButton.Enable = 'on';
                app.PaletadecoresDropDown.Enable = 'on';
                app.ThermalCamera = Flir.Atlas.Live.Device.ThermalCamera(true);
                app.ThermalCamera.Connect(app.CamerasDisponiveis.Item(app.IndexCameraSelecionada-1));
                pause(1);
                app.DetectarPaletas();
                app.DetectarFPS();
                app.FPSdisponveisDropDown.Value = app.ThermalCamera.FrameRateIndex;
                app.DetectarResolucoes();
                app.AparenciaIsotermas();
                app.CorContrasteIsotermas();
                app.CriarImagem();
                if(~isempty(app.Distance))
                    app.ThermalCamera.ThermalImage.ThermalParameters.Distance = app.Distance;
                    app.ThermalCamera.ThermalImage.ThermalParameters.Emissivity = app.Emissivity;
                    app.ThermalCamera.ThermalImage.ThermalParameters.ReflectedTemperature = app.ReflectedTemperature;
                end
                app.XCoord = app.ThermalCamera.ThermalImage.Width/2;
                app.YCoord = app.ThermalCamera.ThermalImage.Height/2;
                while(app.BoolCameraConectada)
                    if(strcmp(app.AtualizarhistogramaMenu.Checked, 'on'))
                        app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.HistogramEqualization;
                    end
                    if(app.MostrarTemperaturaCheckBox.Value)
                        app.TemperaturaEditField.Value = app.ThermalCamera.ThermalImage.GetValueAt(app.Pontos{1}).Value;
                    end
                    set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
                    drawnow;
                end
            else
                app.ThermalCamera = Flir.Atlas.Live.Device.VideoOverlayCamera(true);
                app.ThermalCamera.Connect(app.CamerasDisponiveis.Item(app.IndexCameraSelecionada-1));
                app.CriarImagem();
                while(app.BoolCameraConectada)
                    img = uint8(app.ThermalCamera.VisualImage.ImageArray);
                    set(app.Imagem, 'CData', img);
                    drawnow;
                end
            end
            
            
        end
        
        function seq = GetCamera(app)
            seq = app.ThermalCamera;
        end
        
        function AplicarPropriedades(app, Distance, Emissivity, ReflectedTemperature)
           app.Distance = Distance;
           app.Emissivity = Emissivity;
           app.ReflectedTemperature = ReflectedTemperature;
        end
        
        function SetBoolPlotar(app, bool)
            app.BoolPlotar = bool;
        end
        
        function SetRecordingPath(app, Path)
            app.RecordingPath = Path;
        end
        
        function SetEmissividade(app, Emissividade)
            app.ThermalCamera.ThermalImage.ThermalParameters.Emissivity = Emissividade;
        end
        
    end
    
    
    methods (Access = private)
    
        function DesativarOpcoes(app)
            %Camera
            app.DesconectarcmeraMenu.Enable = 'off';
            
            %Paletas
            app.PaletadecoresDropDown.Enable = 'off';
            
            %Painel Temperatura
            app.MostrarTemperaturaCheckBox.Enable = 'off';
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditField.Enable = 'off';
            app.TemperaturaEditField.Enable = 'off';
            app.PlotarButton.Enable = 'off';
            app.nPontosEditField.Enable = 'off';
            
            %Painel Gravação
            app.IniciargravaoButton.Enable = 'off';
            app.ParargravaoButton.Enable = 'off';
            
            %Painel Reprodução
            app.BotaoPlayPause.Enable = 'off';
            app.BotaoParar.Enable = 'off';
            app.RepetirCheckBox.Enable = 'off';
            app.FrameEditField.Enable = 'off';
            %app.FPSEditField.Enable = 'off';
            
            %Painel Estatísticas
            app.VisualizarCheckBox.Enable = 'off';
            app.TemperaturaMaximaEditField.Enable = 'off'; app.CheckBoxTemperaturaMaxima.Enable = 'off';
            app.TemperaturaMinimaEditField.Enable = 'off'; app.CheckBoxTemperaturaMinima.Enable = 'off';
            app.TemperaturaMediaEditField.Enable = 'off'; app.CheckBoxTemperaturaMedia.Enable = 'off';
            app.DesvioPadroEditField.Enable = 'off'; app.CheckBoxTemperaturaDesvio.Enable = 'off';
            
            %TSR
            app.TamanhooriginalEditField.Enable = 'off';
            app.TamanhodamatrizEditField.Enable = 'off';
            app.CompactacaoEditField.Enable = 'off';
            %app.VisualizarMatrizButton.Enable = 'off';
            
            %Menu Opções
            app.ParmetrosTermicosMenu.Enable = 'off';
        end
        
        
        function DetectarPaletas(app)
            app.Paletas = Flir.Atlas.Image.Palettes.PaletteManager.Palettes;
            PaletasDisponiveis = cell(1, app.Paletas.Count);
            for i=1:app.Paletas.Count
                PaletasDisponiveis{i} = char(app.Paletas.Item(i-1).Name);
            end
            app.PaletadecoresDropDown.Items = PaletasDisponiveis;
        end
        
        function DetectarFPS(app)
            ListFrameRate = app.ThermalCamera.EnumerateFrameRates();
            framerates = cell(ToArray(ListFrameRate));
            app.FPSdisponveisDropDown.Items = framerates;
            app.FPSdisponveisDropDown.ItemsData = 0:ListFrameRate.Count-1;
        end
        
        function DetectarResolucoes(app)
            ListResolucoes = app.ThermalCamera.EnumerateResolutions();
            resolucoes = strings(1, ListResolucoes.Count);
            for i=1:ListResolucoes.Count
                res = ListResolucoes.Item(i-1);
                resolucoes(1,i) = strcat(num2str(res.Width), 'x', num2str(res.Height));
            end
            app.ResoluesDisponveisDropDown.Items = resolucoes;
            app.ResoluesDisponveisDropDown.ItemsData = 0:ListResolucoes.Count-1;
            app.ResoluesDisponveisDropDown.Value = app.ThermalCamera.SelectedResolutionIndex;
        end
        
        function AparenciaIsotermas(app)
            if(isempty(app.AparnciaDropDown.Items))
                app.AparnciaDropDown.Items = {'Solid', 'Contrast', 'Linked', 'FollowY'};
                app.AparnciaDropDown.Value = app.AparnciaDropDown.Items(1);
                app.AparnciaDropDown.ItemsData = {Flir.Atlas.Image.Isotherms.Appearance.Solid...
                                                  Flir.Atlas.Image.Isotherms.Appearance.Contrast...
                                                  Flir.Atlas.Image.Isotherms.Appearance.Linked...
                                                  Flir.Atlas.Image.Isotherms.Appearance.FollowY};
            end
        end
        
        function CorContrasteIsotermas(app)
            if(isempty( app.CordecontrasteDropDown.Items))
                app.CordecontrasteDropDown.Items = {'Vermelho', 'Azul', 'Verde', 'Amarelo', 'Ciano', 'Magenta', 'Cinza'};
                app.CordecontrasteDropDown.Value = {'Vermelho'};
                app.CordecontrasteDropDown.ItemsData = {Flir.Atlas.Image.Isotherms.ContrastColor.Red,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Blue,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Green,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Yellow,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Cyan,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Magenta,...
                                                        Flir.Atlas.Image.Isotherms.ContrastColor.Grey};
            end
        end
        
        function CaminhoSalvar(app)
            if(~exist(strcat(app.PastaVideo, app.NomeVideo, '.seq'), 'file'))
                app.SalvarVideoEditField.Value = strcat(app.PastaVideo, app.NomeVideo, '.seq');
            else
                count = 1;
                while(exist(strcat(app.PastaVideo, app.NomeVideo,'_', num2str(count), '.seq'), 'file')==2)
                    count = count + 1;
                end
                app.SalvarVideoEditField.Value = strcat(app.PastaVideo, app.NomeVideo,'_', num2str(count), '.seq');
            end
            
            if(~exist(strcat(app.PastaImagem, app.NomeImagem, '.bmp'), 'file'))
                app.ImagePath = strcat(app.PastaImagem, app.NomeImagem, '.bmp');
            else
                count = 1;
                while(exist(strcat(app.PastaImagem, app.NomeImagem, '_', num2str(count), '.bmp'), 'file')==2)
                    count = count + 1;
                end
                app.ImagePath = strcat(app.PastaImagem, app.NomeImagem, '_', num2str(count), '.bmp');
            end
            app.SalvarImagemEditField.Value = app.ImagePath;
            
            if(~exist(strcat(app.PastaMatriz, app.NomeMatriz, '.mat'), 'file'))
                app.SalvarmatrizEditField.Value = strcat(app.PastaMatriz, app.NomeMatriz, '.mat');
            else
                count = 1;
                while(exist(strcat(app.PastaMatriz, app.NomeMatriz, '_', num2str(count), '.mat'), 'file')==2)
                    count = count + 1;
                end
                app.SalvarmatrizEditField.Value = strcat(app.PastaMatriz, app.NomeMatriz, '_', num2str(count), '.mat');
            end
            if(isprop(app, 'Coeficientes') && ~isempty(app.Coeficientes) && isfield(app.Coeficientes{end}, 'NomeMatriz'))
                app.CaminhoDerivada = strcat(app.PastaMatriz, app.Coeficientes{end}.NomeMatriz, '_', num2str(app.Coeficientes{end}.GrauDerivada), 'a Derivada.mat');
            end
        end
        
        function CriarImagem(app)
            app.FigureImagem = figure(1);
            app.AxesImagem = axes(app.FigureImagem);
            colormap(app.AxesImagem, gray(256));
            
            if(app.BoolCameraConectada)
                if(strcmp(app.CamerasDisponiveis.Item(app.IndexCameraSelecionada-1).SelectedStreamingFormat, 'FlirFileFormat'))
                    app.Imagem = imshow(uint8(app.ThermalCamera.ThermalImage.ImageArray), 'Parent', app.AxesImagem, 'Border', 'tight');
                else
                    pause(1.5);
                    img = uint8(app.ThermalCamera.VisualImage.ImageArray);
                    app.Imagem = imshow(img, 'Parent', app.AxesImagem, 'Border', 'tight');
                end
            else
                app.Frame = uint8(app.ThermalCamera.ThermalImage.ImageArray);
                app.Imagem = imshow(app.Frame, 'Parent', app.AxesImagem, 'Border', 'tight');
            end
            app.Imagem.CDataMapping = 'scaled';
            truesize(gcf, [360 480]);
            app.Imagem.DeleteFcn = @app.DeleteFcnCallback;
        end
      
        function EventHandlerIndexChanged(app, source, args)
            if(~app.BoolPlotando)
                if(strcmp(app.AtualizarhistogramaMenu.Checked, 'on'))
                    app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.HistogramEqualization;
                end
                app.AtualizarImagem();
                
                app.FrameEditField.Value = double(app.Seq.SelectedIndex + 1);
                if(~isempty(app.Distance) && (app.Distance ~= app.ThermalCamera.ThermalImage.ThermalParameters.Distance ||...
                   app.Emissivity ~= app.ThermalCamera.ThermalImage.ThermalParameters.Emissivity ||...
                   app.ReflectedTemperature ~= app.ThermalCamera.ThermalImage.ThermalParameters.ReflectedTemperature))
               
                    app.ThermalCamera.ThermalImage.ThermalParameters.Distance = app.Distance;
                    app.ThermalCamera.ThermalImage.ThermalParameters.Emissivity = app.Emissivity;
                    app.ThermalCamera.ThermalImage.ThermalParameters.ReflectedTemperature = app.ReflectedTemperature;
                end
                if(app.MostrarTemperaturaCheckBox.Value)
                    Temperatura = app.Seq.ThermalImage.GetValueAt(app.Pontos{1}).Value;
                    app.TemperaturaEditField.Value = Temperatura;
                end
                %Mostrar ponto de maior e menor temperatura
                if(app.VisualizarCheckBox.Value)
                    app.FigureImagem.UserData.Max.XData = app.ThermalCamera.ThermalImage.Statistics.HotSpot.X;
                    app.FigureImagem.UserData.Max.YData = app.ThermalCamera.ThermalImage.Statistics.HotSpot.Y;
                    
                    app.FigureImagem.UserData.Min.XData = app.ThermalCamera.ThermalImage.Statistics.ColdSpot.X;
                    app.FigureImagem.UserData.Min.YData = app.ThermalCamera.ThermalImage.Statistics.ColdSpot.Y;
                    
                    app.TemperaturaMediaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Average.Value;
                    app.TemperaturaMinimaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Min.Value;
                    app.TemperaturaMaximaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Max.Value;
                    app.DesvioPadroEditField.Value = app.ThermalCamera.ThermalImage.Statistics.StandardDeviation.Value;
                end
                
                if(app.LinhasCheckBox.Value)
                    if(fig.Number == 1)
                        temps = double(app.ThermalCamera.ThermalImage.GetValues(app.PontosLinhas));
                        app.PlotLinhas.YData = temps;
                    elseif(fig.Number == 2)
                        img = app.FuncaoReconst(app.FrameEditField.Value);
                        ind = sub2ind(size(img), app.PontosLinhas(2,:), app.PontosLinhas(1,:));
                        temps = img(ind);
                        app.PlotLinhas.YData = temps;
                    end
                    
                end
                if(app.HistogramaCheckBox.Value)
                    data = double(app.ThermalCamera.ThermalImage.GetValues(app.PontosRetangulo));
                    app.FigurePlot.Children.Children.Data = data;
                end
                
            end

        end
        
        function EventHandlerStatusChanged(app, source, args)
           switch args.Status
               case Flir.Atlas.Image.Playback.PlayerStatus.Stopped
                   app.BotaoPlayPause.Icon = '.\play.png';
                   app.BotaoParar.Enable = 'off';
               case Flir.Atlas.Image.Playback.PlayerStatus.Playing
                   app.BotaoPlayPause.Icon = '.\pause.png';
                   app.BotaoParar.Enable = 'on';
               case Flir.Atlas.Image.Playback.PlayerStatus.Paused
                   app.BotaoPlayPause.Icon = '.\play.png';
           end	
        end
        
        function EventHandlerButtonDownFcn(app, source, args) %TODO
            x=args.IntersectionPoint(1); 
            y=args.IntersectionPoint(2);
            fig = gcf;
            fig.UserData.PontoAtual = fig.UserData.PontoAtual + 1;
            if(fig.UserData.PontoAtual > fig.UserData.nPontos)
                fig.UserData.PontoAtual = 1;
            end
            if(fig.UserData.PontoAtual <= fig.UserData.nPontos)
                try
                    plt = fig.UserData.Plots{fig.UserData.PontoAtual};
                    plt.XData = x;
                    plt.YData = y;
                catch ex
                    hold on;
                    fig.UserData.Plots{fig.UserData.PontoAtual} = plot(x, y, 'o');
                    hold off;
                end
                
                if(fig.Number == 1)
                    p = System.Drawing.Point(x-1, y-1);
                    temp = app.ThermalCamera.ThermalImage.GetValueAt(p).Value;
                    app.TemperaturaEditField.Value = double(temp);
                else
                    temp = fig.Children.Children(end).CData(round(y), round(x));
                    app.TemperaturaEditField.Value = temp;
                end
                app.CoordenadaXEditField.Value = x;
                app.CoordenadaYEditField.Value = y;
            end
            if(app.BoolCalcularEmissividade)
                p = System.Drawing.Point(x, y);
                app.CalcularEmissividadeApp.SetTemperatura(double(app.ThermalCamera.ThermalImage.GetValueAt(p).Value));
            end

        end
        
        function EventHandlerImageChanged(app, source, args)
            disp('ImageChanged');
            set(app.Imagem, 'CData', uint8(source.ThermalImage.ImageArray));
        end
        
        function AtualizarTemperaturas(app)
            if(app.MostrarTemperaturaCheckBox.Value)
                Temperatura = app.ThermalCamera.ThermalImage.GetValueAt(app.Pontos{1}).Value;
                app.TemperaturaEditField.Value = Temperatura;
            end
            if(app.VisualizarCheckBox.Value)
                app.TemperaturaMediaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Average.Value;
                app.TemperaturaMinimaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Min.Value;
                app.TemperaturaMaximaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Max.Value;
            end
        end
        
        function DeleteFcnCallback(app, source, args)
            app.MostrarTemperaturaCheckBox.Value = 0;
            if(app.BoolCameraConectada)
                app.BoolCameraConectada = false;
                app.ThermalCamera.Disconnect();
                app.ThermalCamera.Dispose();
            else
                app.ThermalCamera.Stop();
                app.ThermalCamera.First();
                app.ThermalCamera.Dispose();
            end
            app.DesativarOpcoes();
        end      
        
        function LinhaButtonDownCallback(app, src, args)
            if(~isempty(app.Linhas))
                delete(app.Linhas);
            end
            fig = gcf;
            ax = fig.Children;
            coords = ax.CurrentPoint;
            c(:) = 255 - [ax.Children.CData(round(coords(3)),round(coords(1)),:)];
            if(length(c) < 3)
                c(1) = 1;
                c(2:3) = 0;
            end
            hold(ax, 'on');
            app.Linhas = plot([coords(1) coords(1)], [coords(3) coords(3)], '-o', 'Color', c);
            hold(ax, 'off');
            drawnow;
            fig.WindowButtonMotionFcn = @app.LinhaMouseMovingCallback;
            fig.WindowButtonUpFcn = @app.LinhaButtonUpCallback;
        end
        
        function LinhaMouseMovingCallback(app, src, args)
            fig = gcf;
            ax = fig.Children;
            coords = ax.CurrentPoint;
            app.Linhas.XData(2) = coords(1);
            app.Linhas.YData(2) = coords(3);
        end
        
        function LinhaButtonUpCallback(app, src, args)
            fig = gcf;
            ax = fig.Children;
            app.FiguraSelecionada = fig.Number;
            numeroPontos = app.NumerodepontosEditField.Value;
            fig.WindowButtonMotionFcn = [];
            fig.WindowButtonUpFcn = [];
            if(~isempty(app.FigurePlot))
                delete(app.FigurePlot);
            end
            V = [app.Linhas.XData(1) 1; app.Linhas.XData(2) 1];
            p = V \ [app.Linhas.YData(1); app.Linhas.YData(2)];
            x = linspace(app.Linhas.XData(1), app.Linhas.XData(2), numeroPontos);
            y = arrayfun(@(n)p(1)*n+p(2), x);
            if(fig.Number == 1)
                app.PontosLinhas = NET.createArray('System.Drawing.Point', numeroPontos);
                for i=1:length(x)
                    app.PontosLinhas(i).X = x(i);
                    app.PontosLinhas(i).Y = y(i);
                end
                temps = double(app.ThermalCamera.ThermalImage.GetValues(app.PontosLinhas));
            elseif(fig.Number == 2)
                app.PontosLinhas = [round(x);round(y)];
                img = app.FuncaoReconst(app.TempoEditField.Value, size(app.Coeficientes{1}, 3));
                ind = sub2ind(size(img), app.PontosLinhas(2,:), app.PontosLinhas(1,:));
                temps = img(ind);
            end
            app.FigurePlot = figure(3);
            x = linspace(1, numeroPontos, numeroPontos);
            app.PlotLinhas = plot(x, temps);
            xlabel('Ponto');
            ylabel(strcat('Temperatura (', app.Unidade, ')'));
            xlim([min(x(:)) max(x(:))]);
        end

        function AtualizarImagem(app)
            if(~isempty(app.Imagem) && isvalid(app.Imagem))
                if(~app.BoolAplicarFiltros || ~app.AplicarVideoCheckBox.Value)
                    frame = uint8(app.ThermalCamera.ThermalImage.ImageArray);
                    set(app.Imagem, 'CData', frame);
                    drawnow;
                elseif(app.AplicarVideoCheckBox.Value)
                    frame = double(app.ThermalCamera.ThermalImage.ImageProcessing.GetPixelsArray);
                    frame = (frame-min(frame(:)))/(max(frame(:))-min(frame(:)));
                    frame = app.Filtros(frame);
                    set(app.Imagem, 'CData', frame);
                    app.AplicarPaleta(app.PaletaSelecionada);
                    drawnow;
                    limites = double([min(frame(:)) max(frame(:))]);
                    if(any(limites) && ~all(limites))
                        set(app.Imagem.Parent, 'CLim', limites);
                    end
                end
                
            end
            if(~isempty(app.ImagemReconstrucao) && isvalid(app.ImagemReconstrucao))
                frame = app.FuncaoReconst(app.TempoEditField.Value, size(app.Coeficientes{1}, 3));
                if(app.BoolAplicarFiltros && app.AplicarReconstrucaoCheckBox.Value)
                    frame = (frame-min(frame(:)))/(max(frame(:))-min(frame(:)));
                    frame = app.Filtros(frame);
                end
                set(app.ImagemReconstrucao, 'CData', frame);
                limites = [min(frame(:)) max(frame(:))];
                if(any(limites))
                    set(app.ImagemReconstrucao.Parent, 'CLim', limites);
                end
            end
        end


        function retorno = Filtros(app, frame)
            if(~isempty(app.FiltrosatuaisListBox.Items))
                for i=1:length(app.FiltrosatuaisListBox.Items)
                    switch app.FiltrosatuaisListBox.ItemsData{i}.Nome
                        case 'Mediana'
                            tamanho = app.FiltrosatuaisListBox.ItemsData{i}.TamanhoMascara;
                            frame = medfilt2(frame, [tamanho tamanho]);
                        case 'Gaussiana'
                            desvio = app.FiltrosatuaisListBox.ItemsData{i}.DesvioPadrao;
                            tamanho = app.FiltrosatuaisListBox.ItemsData{i}.TamanhoMascara;
                            frame = imgaussfilt(frame, desvio, 'FilterSize', tamanho);
                        case 'Bordas'
                            frame = double(edge(frame, app.FiltrosatuaisListBox.ItemsData{i}.Tipo));
                        case 'Erosão'
                            frame = imerode(frame,app.FiltrosatuaisListBox.ItemsData{i}.ElementoEstruturante);
                        case 'Dilatação'
                            frame = imdilate(frame,app.FiltrosatuaisListBox.ItemsData{i}.ElementoEstruturante);
                        case 'Histograma'
                            frame = histeq(frame);
                        case 'Gamma'
                            frame = frame.^app.FiltrosatuaisListBox.ItemsData{i}.Gamma;
                        case 'Limiar'
                            if(strcmp(app.FiltrosatuaisListBox.ItemsData{i}.Tipo, 'Limiar'))
                                frame = double(frame > app.FiltrosatuaisListBox.ItemsData{i}.Valor(1));
                            elseif(strcmp(app.FiltrosatuaisListBox.ItemsData{i}.Tipo, 'Intervalo'))
                                frame = double(and(frame > app.FiltrosatuaisListBox.ItemsData{i}.Valor(1), frame < app.FiltrosatuaisListBox.ItemsData{i}.Valor(2)));
                            else
                                frame = double(frame > graythresh(frame));
                            end
                    end
                end
            end

            retorno = frame;
        end
        
        function HistogramaButtonDownCallback(app, src, args)
            if(length(app.AxesImagem.Children) > 1)
                delete(app.AxesImagem.Children(1));
            end
            coords = app.AxesImagem.CurrentPoint;
            
            hold(app.AxesImagem, 'on');
            app.RetanguloHistograma = plot([coords(1)], [coords(3)], '-o');
            hold(app.AxesImagem, 'off');
            drawnow;
            app.PontosRetangulo = [coords(1); coords(3)];
            app.FigureImagem.WindowButtonMotionFcn = @app.HistogramaMouseMovingCallback;
            app.FigureImagem.WindowButtonUpFcn = @app.HistogramaButtonUpCallback;
        end
        
        function HistogramaMouseMovingCallback(app, src, args)
            coords = app.AxesImagem.CurrentPoint;
            app.PontosRetangulo(:,2) = [coords(1); coords(3)];
            x = min(app.PontosRetangulo(1,:));
            X = max(app.PontosRetangulo(1,:));
            y = min(app.PontosRetangulo(2,:));
            Y = max(app.PontosRetangulo(2,:));
            xdata = [x X X x x];
            ydata = [y y Y Y y];
            app.AxesImagem.Children(1).XData = xdata;
            app.AxesImagem.Children(1).YData = ydata;
        end
        
        function HistogramaButtonUpCallback(app, src, args)
            app.FigureImagem.WindowButtonMotionFcn = [];
            app.FigureImagem.WindowButtonUpFcn = [];
            if(~isempty(app.FigurePlot))
                delete(app.FigurePlot);
            end
            x = min(app.PontosRetangulo(1,:));
            X = max(app.PontosRetangulo(1,:));
            y = min(app.PontosRetangulo(2,:));
            Y = max(app.PontosRetangulo(2,:));
            
            ret = System.Drawing.Rectangle(x, y, X-x, Y-y);
            data = double(app.ThermalCamera.ThermalImage.GetValues(ret));
            app.PontosRetangulo = ret;
            app.FigurePlot = figure(3);
            histogram(data);
            axis auto;
            ylabel('Número de píxels');
            xlabel(strcat('Temperatura (', app.Unidade, ')'));
        end
        
        function AplicarPaleta(app, PaletaSelecionada)
            indice = sum((strcmp(app.PaletadecoresDropDown.Items, PaletaSelecionada)).*(1:length(app.PaletadecoresDropDown.Items)));
            pal = app.Paletas.Item(indice-1).PaletteColors;
            paleta = zeros(pal.Count, 3);
            for i=0:pal.Count-1
                paleta(i+1, :) = [pal.Item(i).R pal.Item(i).G pal.Item(i).B];
            end
            if(~isempty(app.AxesReconstrucao) && isvalid(app.AxesReconstrucao))
                colormap(app.AxesReconstrucao, mat2gray(paleta));
            end
            if(~isempty(app.AxesImagem) && isvalid(app.AxesImagem))
                colormap(app.AxesImagem, mat2gray(paleta));
            end
        end
        
        function CompararButtonUpCallback(app, src, args)
            fig = gcf;
            ax = gca;
            coords = ax.CurrentPoint;
            try
                plt = fig.UserData.Plots{fig.UserData.PontoAtual};
                plt.XData = coords(1);
                plt.YData = coords(3);
            catch ex
                hold on;
                fig.UserData.Plots{fig.UserData.PontoAtual} = plot(coords(1), coords(3), 'o');
                hold off;
            end
        end
        
        function tamanho = ConverterTamanho(app, bytes)
            if(strcmp(app.BMenu.Checked, 'on'))
                tamanho = bytes;
            elseif(strcmp(app.KBMenu.Checked, 'on'))
                tamanho = bytes/1024;
            elseif(strcmp(app.MBMenu.Checked, 'on'))
                tamanho = (bytes/1024)/1024;
            elseif(strcmp(app.GBMenu.Checked, 'on'))
                tamanho = ((bytes/1024)/1024)/1024;
            end
            
        end
        
        function AjustarRegiaoButtonDownCallback(app, src, args)
            if(length(app.AxesImagem.Children) > 1)
                delete(app.AxesImagem.Children(1));
            end
            coords = app.AxesImagem.CurrentPoint;
            
            hold(app.AxesImagem, 'on');
            app.RetanguloRegiao = plot([coords(1)], [coords(3)], '-');
            hold(app.AxesImagem, 'off');
            drawnow;
            app.PontosRetangulo = [coords(1); coords(3)];
            app.FigureImagem.WindowButtonMotionFcn = @app.AjustarRegiaoMouseMovingCallback;
            app.FigureImagem.WindowButtonUpFcn = @app.AjustarRegiaoButtonUpCallback;
        end
        
        function AjustarRegiaoButtonUpCallback(app, src, args)
            app.FigureImagem.WindowButtonMotionFcn = [];
            app.FigureImagem.WindowButtonUpFcn = [];
            if(~isempty(app.FigurePlot))
                delete(app.FigurePlot);
            end
            x = min(app.PontosRetangulo(1,:));
            X = max(app.PontosRetangulo(1,:));
            y = min(app.PontosRetangulo(2,:));
            Y = max(app.PontosRetangulo(2,:));
            
            ret = System.Drawing.Rectangle(x, y, X-x, Y-y);
            app.ThermalCamera.ThermalImage.Scale.AutoAdjustRegion.Rectangle = ret;
            delete(app.RetanguloRegiao);
            app.FigureImagem.WindowButtonDownFcn = [];
            app.AtualizarImagem();
            
        end
        
        function AjustarRegiaoMouseMovingCallback(app, src, args)
            coords = app.AxesImagem.CurrentPoint;
            app.PontosRetangulo(:,2) = [coords(1); coords(3)];
            x = min(app.PontosRetangulo(1,:));
            X = max(app.PontosRetangulo(1,:));
            y = min(app.PontosRetangulo(2,:));
            Y = max(app.PontosRetangulo(2,:));
            xdata = [x X X x x];
            ydata = [y y Y Y y];
            app.RetanguloRegiao.XData = xdata;
            app.RetanguloRegiao.YData = ydata;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = strcat(getenv('FLIR_Atlas_MATLAB'), 'Flir.Atlas.Image.dll');
            NET.addAssembly(filePath);
            filePath = strcat(getenv('FLIR_Atlas_MATLAB'), 'Flir.Atlas.Live.dll');
            NET.addAssembly(filePath);
            
            app.MostrarTemperaturaCheckBox.Value = 0;
            app.CaminhoSalvar();
            app.DesativarOpcoes();    
            app.DetectarPaletas();
        end

        % Menu selected function: AbrirMenu
        function AbrirMenuSelected(app, event)
            [file, path] = uigetfile({'*.Seq';'*.*'},'Selecionar arquivo .Seq');
            app.UIFigure.Visible = 'off';app.UIFigure.Visible = 'on';
            if(file~=0)
                if(Flir.Atlas.Image.ThermalImageFile.IsThermalImage(strcat(path, file)))
                    if(app.BoolCameraConectada)
                        app.BoolCameraConectada = false;
                        app.ThermalCamera.Disconnect();
                        app.ThermalCamera.Dispose();
                    end
                    if(~isempty(app.FigureImagem))
                        delete(app.FigureImagem);
                    end
                    Arquivo = Flir.Atlas.Image.ThermalImageFile(strcat(path, file));
                    app.Seq = Arquivo.ThermalSequencePlayer();
                    app.XCoord = app.Seq.ThermalImage.Width/2;
                    app.YCoord = app.Seq.ThermalImage.Height/2;
                    app.Pontos{1} = System.Drawing.Point(app.XCoord, app.YCoord);
                    app.MatrizPontos = [app.XCoord; app.YCoord];
                    app.ThermalCamera = app.Seq;
                    app.CriarImagem();
                    app.AparenciaIsotermas();
                    app.CorContrasteIsotermas();
                    app.PaletadecoresDropDown.Enable = 'on';
                    indice = sum((strcmp(app.PaletadecoresDropDown.Items, char(app.ThermalCamera.ThermalImage.Palette.Name))).*(1:length(app.PaletadecoresDropDown.Items)));
                    app.PaletadecoresDropDown.Value = app.PaletadecoresDropDown.Items{indice};
                    app.PaletaSelecionada = app.PaletadecoresDropDown.Value;
                    if(~isempty(app.Distance))
                        app.ThermalCamera.ThermalImage.ThermalParameters.Distance = app.Distance;
                        app.ThermalCamera.ThermalImage.ThermalParameters.Emissivity = app.Emissivity;
                        app.ThermalCamera.ThermalImage.ThermalParameters.ReflectedTemperature = app.ReflectedTemperature;
                    end
                    addlistener(app.Seq, 'SelectedIndexChanged', @app.EventHandlerIndexChanged);
                    addlistener(app.Seq, 'StatusChanged', @app.EventHandlerStatusChanged);
                    set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
                    app.MostrarTemperaturaCheckBox.Enable = 'on';
                    app.BotaoPlayPause.Enable = 'on';
                    app.RepetirCheckBox.Enable = 'on';
                    app.PlotarButton.Enable = 'on';
                    app.FrameEditField.Enable = 'on';
                    app.VisualizarCheckBox.Enable = 'on';
                    app.TamanhooriginalEditField.Enable = 'on';
                    app.FPSEditField.Enable = 'on';
                    app.ParmetrosTermicosMenu.Enable = 'on';
                    
                    app.GerarMatrizButton.Enable = 'on';
                    
                    %app.FrameEditField.Limits = [1, double(app.ThermalCamera.Count)];
                    app.FrameEditField.Value = double(app.Seq.SelectedIndex + 1);
                    app.TamanhoArquivo = dir(strcat(path, file));
                    app.TamanhooriginalEditField.Value = app.ConverterTamanho(app.TamanhoArquivo.bytes);
                    app.FPSEditField.Value = app.ThermalCamera.FrameRate;
                    
                    app.TemperaturaMenu_2.Checked = 'off';
                    app.SinalMenu.Checked = 'off';
                    app.EqdeHistogramaMenu.Checked = 'off';
                    app.AtualizarhistogramaMenu.Checked = 'off';
                    app.AtualizarhistogramaMenu.Enable = 'off';
                    switch Arquivo.ColorDistribution
                        case Flir.Atlas.Image.ColorDistribution.TemperatureLinear 
                            app.TemperaturaMenu_2.Checked = 'on';
                        case Flir.Atlas.Image.ColorDistribution.HistogramEqualization
                            app.EqdeHistogramaMenu.Checked = 'on';
                            app.AtualizarhistogramaMenu.Enable = 'on';
                        case Flir.Atlas.Image.ColorDistribution.SignalLinear
                            app.SinalMenu.Checked = 'on';
                    end
                else
                    errordlg('Arquivo inválido!');
                    app.UIFigure.Visible = 'off';app.UIFigure.Visible = 'on';
                end
            end
        end

        % Menu selected function: ProcurarCmerasMenu
        function ProcurarCmerasMenuSelected(app, event)
            Discovery = Flir.Atlas.Live.Discovery.Discovery;
            app.CamerasDisponiveis = Discovery.Start(3);
            Cameras = strings(1, app.CamerasDisponiveis.Count);
            for i=1:size(Cameras, 2)
                Cameras(1, i) = strcat(char(app.CamerasDisponiveis.Item(i-1).Name), '::', char(app.CamerasDisponiveis.Item(i-1).SelectedStreamingFormat));
            end
            Cameras = cellstr(Cameras);
            diag = SelecionarCamera(app, Cameras);
            waitfor(diag);
            if(app.IndexCameraSelecionada ~=0)
                app.setCamera();
            end
        end

        % Callback function: SairMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.BoolCameraConectada)   
                app.ThermalCamera.Disconnect();
                app.ThermalCamera.Dispose();
            end
            if(app.BoolPlotar)
                delete(app.PlotarApp);
            end
            delete(findall(0));
            delete(app);
        end

        % Menu selected function: DesconectarcmeraMenu
        function DesconectarcmeraMenuSelected(app, event)
            app.BoolCameraConectada = false;
            app.ThermalCamera.Disconnect();
            app.ThermalCamera.Dispose();
        end

        % Value changed function: PaletadecoresDropDown
        function PaletadecoresDropDownValueChanged(app, event)
            app.PaletaSelecionada = app.PaletadecoresDropDown.Value;
            if(~isempty(app.AxesImagem) && isvalid(app.AxesImagem))
                indice = sum((strcmp(app.PaletadecoresDropDown.Items, char(app.PaletadecoresDropDown.Value))).*(1:length(app.PaletadecoresDropDown.Items)));
                app.ThermalCamera.ThermalImage.Palette = app.Paletas.Item(indice-1);
                set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
            end
            if(~isempty(app.AxesReconstrucao) && isvalid(app.AxesReconstrucao))
                app.AplicarPaleta(app.PaletaSelecionada);
            end
        end

        % Value changed function: MostrarTemperaturaCheckBox
        function MostrarTemperaturaCheckBoxValueChanged(app, event)
            value = app.MostrarTemperaturaCheckBox.Value;
            fig = gcf;
            
            if(value)
                if(~isempty(app.FigureImagem) && isvalid(app.FigureImagem))
                    app.Imagem.ButtonDownFcn = @app.EventHandlerButtonDownFcn;
                    app.FigureImagem.UserData.nPontos = app.nPontosEditField.Value;
                    app.FigureImagem.UserData.PontoAtual = 0;
                end
                if(~isempty(app.FigureReconstrucao) && isvalid(app.FigureReconstrucao))
                    app.ImagemReconstrucao.ButtonDownFcn = @app.EventHandlerButtonDownFcn;
                    app.FigureReconstrucao.UserData.nPontos = app.nPontosEditField.Value;
                    app.FigureReconstrucao.UserData.PontoAtual = 0;
                end
                app.CoordenadaXEditField.Enable = 'on';
                app.CoordenadaYEditField.Enable = 'on';
                app.TemperaturaEditField.Enable = 'on';
                app.nPontosEditField.Enable = 'on';
            else
                if(~isempty(app.FigureImagem) && isvalid(app.FigureImagem))
                    if(isfield(app.FigureImagem.UserData, 'Plots'))
                        cellfun(@delete, app.FigureImagem.UserData.Plots);
                        app.FigureImagem.UserData = rmfield(app.FigureImagem.UserData, 'Plots');
                    end
                    app.FigureImagem.UserData.nPontos = value;
                    app.FigureImagem.UserData.PontoAtual = 1;
                end
                if(~isempty(app.FigureReconstrucao) && isvalid(app.FigureReconstrucao))
                    if(isfield(app.FigureReconstrucao.UserData, 'Plots'))
                        cellfun(@delete, app.FigureReconstrucao.UserData.Plots);
                        app.FigureReconstrucao.UserData = rmfield(app.FigureReconstrucao.UserData, 'Plots');
                    end
                    app.FigureReconstrucao.UserData.nPontos = value;
                    app.FigureReconstrucao.UserData.PontoAtual = 1;
                end
                app.CoordenadaXEditField.Enable = 'off';
                app.CoordenadaYEditField.Enable = 'off';
                app.TemperaturaEditField.Enable = 'off';
                app.nPontosEditField.Enable = 'off';
            end
            
        end

        % Value changed function: RepetirCheckBox
        function RepetirCheckBoxValueChanged(app, event)
            value = app.RepetirCheckBox.Value;
            app.Seq.Loop = value;
        end

        % Button pushed function: BotaoPlayPause
        function BotaoPlayPausePushed(app, event)
            if(app.Seq.SelectedIndex == app.Seq.Count - 1)
                app.ThermalCamera.Stop();
                app.ThermalCamera.First();
            end
            status = char(app.ThermalCamera.Status);
            if(~strcmp(status, 'Playing'))
                app.ThermalCamera.Play();
            else
                app.ThermalCamera.Pause();
            end
        end

        % Button pushed function: BotaoParar
        function BotaoPararPushed(app, event)
            app.Seq.Stop();     
        end

        % Button pushed function: PlotarButton
        function PlotarButtonPushed(app, event)
            app.BoolPlotando = true;
            fig = gcf;
            nPontos = length(fig.UserData.Plots);
            pontos = NET.createArray('System.Drawing.Point', nPontos);
            for i=1:nPontos
                pontos(i).X = fig.UserData.Plots{i}.XData-1;
                pontos(i).Y = fig.UserData.Plots{i}.YData-1;
            end
            if(isempty(app.FigurePlot) || ~isvalid(app.FigurePlot))
                app.FigurePlot = figure(3);
                app.AxesPlot = axes(app.FigurePlot);
                xlabel(app.AxesPlot, 'Tempo (s)');
                ylabel(app.AxesPlot, strcat('Temperatura (', app.Unidade, ')'));
            end
            legendas = cell(1,nPontos);
            for i=1:nPontos
                legendas{i} = num2str(i);
            end
            app.LinhasAnimadas = [];
            app.LinhasAnimadas = cell(1,nPontos);
            if(isempty(app.Coeficientes) || app.Coeficientes{end}.GrauDerivada == 0)
                for i=1:nPontos
                    app.LinhasAnimadas{i} = animatedline(app.AxesPlot, 'Color', fig.UserData.Plots{i}.Color);
                end
            else
                yyaxis(app.AxesPlot, 'right');
                for i=1:nPontos
                    app.LinhasAnimadas{i} = animatedline(app.AxesPlot, 'Color', fig.UserData.Plots{i}.Color, 'LineStyle', '--');
                end
            end
            if(fig.Number == 1)
                app.Seq.First();
                xlim(app.AxesPlot, [1 app.ThermalCamera.Duration.TotalSeconds]);
                spf = double(app.ThermalCamera.Duration.TotalSeconds) / double(app.ThermalCamera.Count);
                while(app.BoolPlotando)
                    if(app.ThermalCamera.SelectedIndex == 1)
                        legend(app.AxesPlot, legendas);
                    end
                    temps = app.ThermalCamera.ThermalImage.GetValues(pontos);
                    for i=1:nPontos
                        temperatura = temps(i);
                        addpoints(app.LinhasAnimadas{i}, double(app.ThermalCamera.SelectedIndex)*spf, temperatura);
                        drawnow limitrate;
                    end
                    app.BoolPlotando = app.Seq.Next();
                end
            else
                if(app.Coeficientes{end}.Dividir)
                    funcao = @(x, TempoMax, p1, p2)(polyval(p1, x).*(x < TempoMax)) +...
                                 (polyval(p2, x).*(x >= TempoMax));
                else
                    funcao = @(x, TempoMax, p1, p2)polyval(p1, x);
                end
                if(~strcmp(app.Coeficientes{end}.Modelo, 'Polinômio'))
                    FuncaoPolinomio = @(x, TempoMax, p1, p2)exp(funcao(log(double(x+1)), TempoMax, p1, p2));
                else
                    FuncaoPolinomio = @(x, TempoMax, p1, p2)funcao(x, TempoMax, p1, p2);
                end
                spf = double(app.Coeficientes{end}.Tempo) / double(app.Coeficientes{end}.nFrames);
                xlim(app.AxesPlot, [1 app.Coeficientes{end}.Tempo]);
                nPontos = length(fig.UserData.Plots);
                x = zeros(1, nPontos);
                y = x;
                for j=1:nPontos
                    y(j) = round(fig.UserData.Plots{j}.XData);
                    x(j) = round(fig.UserData.Plots{j}.YData);
                end
                for i=1:double(app.Coeficientes{end}.nFrames)
                    tempo = spf * i;
                    if(i == 2)
                        legend(app.AxesPlot, legendas);
                    end
                    for k=1:nPontos
                        if(app.Coeficientes{end}.Dividir)
                            p1(:) = app.Coeficientes{1}(x(k), y(k), :);
                            p2(:) = app.Coeficientes{2}(x(k), y(k), :);
                            TempoMax = app.MatrizTempo(x(k), y(k));
                        else
                            p1(:) = app.Coeficientes{1}(x(k), y(k), :);
                            p2 = [];
                            TempoMax = [];
                        end
                        temperatura = FuncaoPolinomio(tempo, TempoMax, p1, p2);
                        addpoints(app.LinhasAnimadas{k}, double(tempo), temperatura);
                        drawnow limitrate;
                    end
                end
            end
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            if(value > app.Seq.Count)
                app.Seq.SelectedIndex = app.Seq.Count-1;
                app.FrameEditField.Value = double(app.Seq.Count);
            else
                app.Seq.SelectedIndex = app.FrameEditField.Value-1;
            end
            if(strcmp(app.AtualizarhistogramaMenu.Checked, 'on'))
                app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.HistogramEqualization;
            end
            app.TempoEditField.Value = app.FrameEditField.Value/double(app.ThermalCamera.FrameRate);
            app.AtualizarImagem();
        end

        % Button pushed function: IniciargravaoButton
        function IniciargravaoButtonPushed(app, event)
            app.ThermalCamera.Recorder.Start(app.SalvarVideoEditField.Value);
            app.IniciargravaoButton.Enable = 'off';
            app.ParargravaoButton.Enable = 'on';
        end

        % Button pushed function: ParargravaoButton
        function ParargravaoButtonPushed(app, event)
            app.ThermalCamera.Recorder.Stop();
            app.IniciargravaoButton.Enable = 'on';
            app.ParargravaoButton.Enable = 'off';
            app.CaminhoSalvar();
        end

        % Button pushed function: AdicionarAcimaButton
        function AdicionarAcimaButtonPushed(app, event)
            texto = {'Entre com a temperatura mínima:'};
            titulo = 'Temperatura mínima da isoterma.';
            dims = [1 35];
            retorno = inputdlg(texto,titulo,dims);
            
            if(~isempty(retorno))
                Valor = str2double(retorno{1});
                if(isnan(Valor))
                    warndlg('Valor inválido!');
                else
                    app.ThermalCamera.ThermalImage.Isotherms.AddAbove;
                    isoterma = app.ThermalCamera.ThermalImage.Isotherms.Item(app.ThermalCamera.ThermalImage.Isotherms.Count-1);
                    isoterma.Threshold = Valor;
                    isoterma.Color = System.Drawing.Color.FromArgb(app.REditField.Value, app.GEditField.Value, app.BEditField.Value);
                    isoterma.Appearance = app.AparnciaDropDown.Value;
                    isoterma.ContrastColor = app.CordecontrasteDropDown.Value;
                    set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
                    drawnow;
                end
            end
        end

        % Button pushed function: AdicionarAbaixoButton
        function AdicionarAbaixoButtonPushed(app, event)
            texto = {'Entre com a temperatura máxima:'};
            titulo = 'Temperatura máxima da isoterma.';
            dims = [1 35];
            retorno = inputdlg(texto,titulo,dims);
            
            if(~isempty(retorno))
                Valor = str2double(retorno{1});
                if(isnan(Valor))
                    warndlg('Valor inválido!');
                else
                    app.ThermalCamera.ThermalImage.Isotherms.AddBelow;
                    isoterma = app.ThermalCamera.ThermalImage.Isotherms.Item(app.ThermalCamera.ThermalImage.Isotherms.Count-1);
                    isoterma.Threshold = Valor;
                    isoterma.Color = System.Drawing.Color.FromArgb(app.REditField.Value, app.GEditField.Value, app.BEditField.Value);
                    isoterma.Appearance = app.AparnciaDropDown.Value;
                    isoterma.ContrastColor = app.CordecontrasteDropDown.Value;
                    set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
                    drawnow;
                end
            end
        end

        % Button pushed function: RemoverButton
        function RemoverButtonPushed(app, event)
            app.ThermalCamera.ThermalImage.Isotherms.Clear();
            set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
            drawnow;
        end

        % Value changed function: SalvarVideoEditField
        function SalvarVideoEditFieldValueChanged(app, event)
%             value = app.SalvarVideoEditField.Value;
%             app.RecordingPath = value;
        end

        % Value changed function: SalvarImagemEditField
        function SalvarImagemEditFieldValueChanged(app, event)
            value = app.SalvarImagemEditField.Value;
            app.ImagePath = value;
        end

        % Button pushed function: BotaoMenuVideo
        function BotaoMenuVideoButtonPushed(app, event)
            [file, path] = uiputfile({'*.seq', 'Vídeo térmico (*.seq)'; '*.*', 'Todos os arquivos (*.*)'});
            if(length(file) > 4 && (all(file(end-3:end) == '.seq')))
                app.NomeVideo = file(1:end-4);
            else
                app.NomeVideo = file;
            end
            app.PastaVideo = path;
            app.SalvarVideoEditField.Value = strcat(app.PastaVideo, app.NomeVideo, '.seq');
        end

        % Button pushed function: BotaoMenuImagem
        function BotaoMenuImagemButtonPushed(app, event)
            [file, path] = uiputfile({'*.bmp', 'Bitmap (*.bmp)'; '*.*', 'Todos os arquivos (*.*)'});
            if(length(file) > 4 && (all(file(end-3:end) == '.bmp')))
                app.NomeImagem = file(1:end-4);
            else
                app.NomeImagem = file;
            end
            app.PastaImagem = path;
            app.SalvarImagemEditField.Value = strcat(app.PastaImagem, app.NomeImagem, '.bmp');
        end

        % Button pushed function: SalvarImagemButton
        function SalvarImagemButtonPushed(app, event)
            app.ThermalCamera.ThermalImage.Image.Save(app.SalvarImagemEditField.Value);
            app.CaminhoSalvar();
        end

        % Value changed function: nPontosEditField
        function nPontosEditFieldValueChanged(app, event)
            value = app.nPontosEditField.Value;
            if(~isempty(app.FigureImagem) && isvalid(app.FigureImagem))
                if(isfield(app.FigureImagem.UserData, 'Plots'))
                    cellfun(@delete, app.FigureImagem.UserData.Plots);
                    app.FigureImagem.UserData = rmfield(app.FigureImagem.UserData, 'Plots');
                end
                app.FigureImagem.UserData.nPontos = value;
                fig.FigureImagem.PontoAtual = 1;
            end
            if(~isempty(app.FigureReconstrucao) && isvalid(app.FigureReconstrucao))
                if(isfield(app.FigureReconstrucao.UserData, 'Plots'))
                    cellfun(@delete, app.FigureReconstrucao.UserData.Plots);
                    app.FigureReconstrucao.UserData = rmfield(app.FigureReconstrucao.UserData, 'Plots');
                end
                app.FigureReconstrucao.UserData.nPontos = value;
                fig.FigureReconstrucao.PontoAtual = 1;
            end
            drawnow;
        end

        % Value changed function: VisualizarCheckBox
        function VisualizarCheckBoxValueChanged(app, event)
            value = app.VisualizarCheckBox.Value;
            app.MostrarTemperaturaCheckBox.Value = 0;
            app.MostrarTemperaturaCheckBoxValueChanged();
            fig = gcf;
            ax = gca;
            if(value)
                app.TemperaturaMaximaEditField.Enable = 'on'; app.CheckBoxTemperaturaMaxima.Enable = 'on';
                app.TemperaturaMinimaEditField.Enable = 'on'; app.CheckBoxTemperaturaMinima.Enable = 'on';
                app.TemperaturaMediaEditField.Enable = 'on'; app.CheckBoxTemperaturaMedia.Enable = 'on';
                app.DesvioPadroEditField.Enable = 'on'; app.CheckBoxTemperaturaDesvio.Enable = 'on';         
                if(fig.Number == 1)
                    app.TemperaturaMediaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Average.Value;
                    app.TemperaturaMinimaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Min.Value;
                    app.TemperaturaMaximaEditField.Value = app.ThermalCamera.ThermalImage.Statistics.Max.Value;
                    app.DesvioPadroEditField.Value = app.ThermalCamera.ThermalImage.Statistics.StandardDeviation.Value;
                    hold(app.AxesImagem, 'on')
                    fig.UserData.Min = plot(ax, app.ThermalCamera.ThermalImage.Statistics.ColdSpot.X,...
                                                app.ThermalCamera.ThermalImage.Statistics.ColdSpot.Y, 'o', 'color', 'blue');
                    fig.UserData.Max = plot(ax, app.ThermalCamera.ThermalImage.Statistics.HotSpot.X,...
                                                app.ThermalCamera.ThermalImage.Statistics.HotSpot.Y, 'o', 'color', 'red');
                    hold(app.AxesImagem, 'off');
                elseif(fig.Number == 2)
                    app.TemperaturaMediaEditField.Value = mean(ax.Children.CData(:));
                    app.TemperaturaMinimaEditField.Value = min(ax.Children.CData(:));
                    app.TemperaturaMaximaEditField.Value = max(ax.Children.CData(:));
                    app.DesvioPadroEditField.Value = std(ax.Children.CData(:));
                end
            else
                if(isfield(fig.UserData, 'Min'))
                    delete(fig.UserData.Min);
                    delete(fig.UserData.Max);
                end
                app.TemperaturaMaximaEditField.Enable = 'off'; app.CheckBoxTemperaturaMaxima.Enable = 'off';
                app.TemperaturaMinimaEditField.Enable = 'off'; app.CheckBoxTemperaturaMinima.Enable = 'off';
                app.TemperaturaMediaEditField.Enable = 'off'; app.CheckBoxTemperaturaMedia.Enable = 'off';
                app.DesvioPadroEditField.Enable = 'off'; app.CheckBoxTemperaturaDesvio.Enable = 'off';
            end
        end

        % Button pushed function: PlotarpropriedadesButton
        function PlotarpropriedadesButtonPushed(app, event)
            n_plots = app.CheckBoxTemperaturaMedia.Value + app.CheckBoxTemperaturaMinima.Value +...
                      app.CheckBoxTemperaturaMaxima.Value + app.CheckBoxTemperaturaDesvio.Value;
            if(n_plots == 0)
                warndlg('Nenhuma propriedade selecionada!');
            else
                delete(app.FigurePlot);
                app.FigurePlot = figure(3);
                ax = axes(app.FigurePlot);
                xlim(ax, [1 app.ThermalCamera.Count]);
                xlabel(ax, 'Frame');
                ylabel(ax, strcat('Temperatura (', app.Unidade, ')'));
                if(app.CheckBoxTemperaturaDesvio.Value)
                    yyaxis right
                    ylabel(ax, 'Desvio padrão');
                    yyaxis left
                end
                cores = colormap(lines);
                ax.ColorOrder = cores(1:7, :);
                app.BoolPlotando = true;
                plots = cell(1, n_plots);
                legenda = cell(1, n_plots);
                for i = 1:n_plots
                    if(app.CheckBoxTemperaturaDesvio.Value && i == n_plots)
                        yyaxis right
                        plots{i} = animatedline(ax, 'color', ax.ColorOrder(ax.ColorOrderIndex, :), 'LineStyle', '--');
                        ax.ColorOrderIndex = ax.ColorOrderIndex+1;
                        if(ax.ColorOrderIndex == 7)
                            ax.ColorOrderIndex = 1;
                        end
                        yyaxis left
                    else
                        plots{i} = animatedline(ax, 'color', ax.ColorOrder(ax.ColorOrderIndex, :));
                        ax.ColorOrderIndex = ax.ColorOrderIndex+1;
                        if(ax.ColorOrderIndex == 7)
                            ax.ColorOrderIndex = 1;
                        end
                    end
                end
                app.ThermalCamera.First();
                count = 1;
                while(app.BoolPlotando)
                    index = double(app.ThermalCamera.SelectedIndex+1);
                    estatisticas = app.ThermalCamera.ThermalImage.Statistics;
                    if(app.CheckBoxTemperaturaMedia.Value)
                        addpoints(plots{count}, index, estatisticas.Average.Value);
                        if(index == 2)
                            legenda{count} = 'Temperatura Média';
                        end
                        count = count + 1;
                    end
                    if(app.CheckBoxTemperaturaMinima.Value)
                        addpoints(plots{count}, index, estatisticas.Min.Value);
                        if(index == 2)
                            legenda{count} = 'Temperatura Mínima';
                        end
                        count = count + 1;
                    end
                    if(app.CheckBoxTemperaturaMaxima.Value)
                        addpoints(plots{count}, index, estatisticas.Max.Value);
                        if(index == 2)
                            legenda{count} = 'Temperatura Máxima';
                        end
                        count = count + 1;
                    end
                    if(app.CheckBoxTemperaturaDesvio.Value)
                        addpoints(plots{count}, index, estatisticas.StandardDeviation.Value);
                        if(index == 2)
                            legenda{count} = 'Desvio Padrão';
                        end
                    end
                    if(index == 2)
                        legend(legenda);
                    end
                    count = 1;
                    drawnow limitrate;
                    app.BoolPlotando = app.ThermalCamera.Next();
                end
                
            end
        end

        % Button pushed function: GerarMatrizButton
        function Reconstruction(app, event)
            app.Grau = app.GrauPolinomioEditField.Value;
            if(app.DividirLentoCheckBox.Value)
                [app.Coeficientes, app.rquadrado, app.MatrizTempo] = TSR(app.ThermalCamera, app.Grau, app.FuncaodeajusteDropDown.Value, app.DividirLentoCheckBox.Value, app.FiltrarCheckBox.Value, app.TamanhodofiltroEditField.Value);
                i1 = find(app.SalvarmatrizEditField.Value == '\', 1, 'last')+1;
                i2 = find(app.SalvarmatrizEditField.Value == '.', 1, 'last')-1;
                app.Coeficientes{end}.NomeMatriz = app.SalvarmatrizEditField.Value(i1:i2);
                Coeficientes =  app.Coeficientes;
                rquadrado = app.rquadrado;
                matriztempo = app.MatrizTempo;
                save(app.SalvarmatrizEditField.Value, 'Coeficientes', 'rquadrado', 'matriztempo');
                clear('Coeficientes', 'rquadrado', 'matriztempo');
            else
                [app.Coeficientes, app.rquadrado] = TSR(app.ThermalCamera, app.Grau, app.FuncaodeajusteDropDown.Value, app.DividirLentoCheckBox.Value, app.FiltrarCheckBox.Value, app.TamanhodofiltroEditField.Value);
                Coeficientes =  app.Coeficientes;
                rquadrado = app.rquadrado;
                save(app.SalvarmatrizEditField.Value, 'Coeficientes', 'rquadrado');
                clear('Coeficientes');
            end
            
            app.TamanhoMatriz = dir(app.SalvarmatrizEditField.Value);
            TamanhoBytes = app.TamanhoMatriz.bytes;
            app.TamanhodamatrizEditField.Enable = 'on';
            app.CompactacaoEditField.Enable = 'on';
            app.VisualizarMatrizButton.Enable = 'on';
            app.CompactacaoEditField.Value = ((app.TamanhoArquivo.bytes - app.TamanhoMatriz.bytes)/app.TamanhoArquivo.bytes)*100;
            Tamanho = app.ConverterTamanho(TamanhoBytes);
            app.TamanhodamatrizEditField.Value = Tamanho;
            tmp = [app.rquadrado{:}];
            app.MenorrEditField.Value = min(tmp(:));
            app.MaiorrEditField.Value = max(tmp(:));
            app.CaminhoSalvar();
            app.VisualizarreconstruoButton.Enable = 'on';
            app.DerivarButton.Enable = 'on';
            app.VisualizarreconstruoButtonPushed();
        end

        % Button pushed function: VisualizarMatrizButton
        function VisualizarMatrizButtonPushed(app, event)
            figure;
            if(strcmp(app.MatrizDropDown.Value.Tipo, 'Matriz'))
                img = app.Coeficientes{app.MatrizDropDown.Value.IndiceCell}(:,:,app.MatrizDropDown.Value.IndiceMatriz);
            else
                img = app.rquadrado{app.MatrizDropDown.Value.IndiceCell};
            end
            imshow(img, []);
        end

        % Value changed function: FuncaodeajusteDropDown, 
        % GrauPolinomioEditField
        function GrauPolinomioEditFieldValueChanged(app, event)
            grau = app.GrauPolinomioEditField.Value;
            funcao = app.FuncaodeajusteDropDown.Value;
            if(strcmp(funcao, 'Polinômio'))
                if(grau > 1)
                    for i=grau:-1:2
                        if i==grau
                            polinomio = strcat('T(t) = a', num2str(grau-i+1), '*t^', num2str(i));
                        else
                            polinomio = strcat(polinomio, ' + a', num2str(grau-i+1), '*t^', num2str(i));
                        end
                    end
                    polinomio = strcat(polinomio, ' + a', num2str(grau), '*t + a', num2str(grau+1));
                elseif(grau == 1)
                    polinomio = 'T(t) = a1*t + a2';
                else
                    polinomio = 'T(t) = a1';
                end
            else
                if(grau > 1)
                    for i=grau:-1:2
                        if i==grau
                            polinomio = strcat('T(t) = exp(a', num2str(grau-i+1), '*log(t)^', num2str(i));
                        else
                            polinomio = strcat(polinomio, ' + a', num2str(grau-i+1), '*log(t)^', num2str(i));
                        end
                    end
                    polinomio = strcat(polinomio, ' + a', num2str(grau), '*log(t) + a', num2str(grau+1), ')');
                elseif(grau == 1)
                    polinomio = 'T(t) = exp(a1*log(t) + a2)';
                else
                    polinomio = 'T(t) = exp(a1)';
                end
            end
            app.PolinomioLabel.Text = polinomio;
        end

        % Menu selected function: CelsiusMenu
        function CelsiusMenuSelected(app, event)
            app.CelsiusMenu.Checked = 'on';
            app.KelvinMenu.Checked = 'off';
            app.FahrenheitMenu.Checked = 'off';
            app.Unidade = '°C';
            app.ThermalCamera.ThermalImage.TemperatureUnit = Flir.Atlas.Image.TemperatureUnit.Celsius;
            app.AtualizarTemperaturas();
        end

        % Menu selected function: KelvinMenu
        function KelvinMenuSelected(app, event)
            app.KelvinMenu.Checked = 'on';
            app.CelsiusMenu.Checked = 'off';
            app.FahrenheitMenu.Checked = 'off';
            app.Unidade = 'K';
            app.ThermalCamera.ThermalImage.TemperatureUnit = Flir.Atlas.Image.TemperatureUnit.Kelvin;
            app.AtualizarTemperaturas();
        end

        % Menu selected function: FahrenheitMenu
        function FahrenheitMenuSelected(app, event)
            app.FahrenheitMenu.Checked = 'on';
            app.CelsiusMenu.Checked = 'off';
            app.KelvinMenu.Checked = 'off';
            app.Unidade = '°F';
            app.ThermalCamera.ThermalImage.TemperatureUnit = Flir.Atlas.Image.TemperatureUnit.Fahrenheit;
            app.AtualizarTemperaturas();
        end

        % Menu selected function: CalcularEmissividadeMenu
        function CalcularEmissividadeMenuSelected(app, event)
            app.BoolCalcularEmissividade = true;
            app.MostrarTemperaturaCheckBox.Value = 0;
            app.MostrarTemperaturaCheckBoxValueChanged();
            if(~isempty(app.FigureImagem) && isvalid(app.FigureImagem))
                app.Imagem.ButtonDownFcn = @app.EventHandlerButtonDownFcn;
                app.FigureImagem.UserData.nPontos = 1;
                app.FigureImagem.UserData.PontoAtual = 0;
            end
            app.CalcularEmissividadeApp = CalcularEmissividade(app, app.ThermalCamera);
            waitfor(app.CalcularEmissividadeApp);
            app.BoolCalcularEmissividade = false;
            
        end

        % Value changed function: LinhasCheckBox
        function LinhasCheckBoxValueChanged(app, event)
            value = app.LinhasCheckBox.Value;
            fig = gcf;
            if(value)
                app.MostrarTemperaturaCheckBox.Value = 0;
                app.MostrarTemperaturaCheckBoxValueChanged;
                app.VisualizarCheckBox.Value = 0;
                app.VisualizarCheckBoxValueChanged;
                fig = gcf;
                fig.WindowButtonDownFcn = @app.LinhaButtonDownCallback;
            else
                delete(app.Linhas);
                fig.WindowButtonDownFcn = [];
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uiputfile({'*.mat', 'Matriz do matlab (*.mat)'; '*.*', 'Todos os arquivos (*.*)'});
            if(length(file) > 4 && (all(file(end-3:end) == '.mat')))
                app.NomeMatriz = file(1:end-4);
            else
                app.NomeMatriz = file;
            end
            app.PastaMatriz = path;
            app.CaminhoSalvar();
            %app.SalvarVideoEditField.Value = strcat(app.PastaMatriz, app.NomeMatriz, '.mat');
        end

        % Menu selected function: TemperaturaMenu_2
        function TemperaturaMenu_2Selected(app, event)
            app.TemperaturaMenu_2.Checked = 'on';
            app.SinalMenu.Checked = 'off';
            app.EqdeHistogramaMenu.Checked = 'off';
            app.AtualizarhistogramaMenu.Enable = 'off';
            app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.TemperatureLinear;
            app.AtualizarImagem();
        end

        % Menu selected function: SinalMenu
        function SinalMenuSelected(app, event)
            app.TemperaturaMenu_2.Checked = 'off';
            app.SinalMenu.Checked = 'on';
            app.EqdeHistogramaMenu.Checked = 'off';
            app.AtualizarhistogramaMenu.Enable = 'off';
            app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.SignalLinear;
            app.AtualizarImagem();
        end

        % Menu selected function: EqdeHistogramaMenu
        function EqdeHistogramaMenuSelected(app, event)
            app.TemperaturaMenu_2.Checked = 'off';
            app.SinalMenu.Checked = 'off';
            app.EqdeHistogramaMenu.Checked = 'on';
            app.AtualizarhistogramaMenu.Enable = 'on';
            app.ThermalCamera.ThermalImage.ColorDistribution = Flir.Atlas.Image.ColorDistribution.HistogramEqualization;
            app.AtualizarImagem();
        end

        % Button pushed function: AdicionarIntervaloButton
        function AdicionarIntervaloButtonPushed(app, event)
            texto = {'Entre com a temperatura mínima do intervalo:'; 'Entre com a temperatura máxima do intervalo:'};
            titulo = 'Temperatura máxima da isoterma.';
            dims = [1 35];
            retorno = inputdlg(texto,titulo,dims);
            if(~isempty(retorno))
                minima = str2double(retorno{1});
                maxima = str2double(retorno{2});
                if(isnan(minima) || isnan(maxima))
                    warndlg('Valor inválido!');
                else
                    intervalo = NET.createGeneric('Flir.Atlas.Image.Range', {'System.Double'}, minima, maxima);

                    app.ThermalCamera.ThermalImage.Isotherms.AddInterval;
                    isoterma = app.ThermalCamera.ThermalImage.Isotherms.Item(app.ThermalCamera.ThermalImage.Isotherms.Count-1);
                    isoterma.Interval = intervalo;
                    isoterma.Color = System.Drawing.Color.FromArgb(app.REditField.Value, app.GEditField.Value, app.BEditField.Value);
                    isoterma.Appearance = app.AparnciaDropDown.Value;
                    isoterma.ContrastColor = app.CordecontrasteDropDown.Value;
                    set(app.Imagem, 'CData', uint8(app.ThermalCamera.ThermalImage.ImageArray));
                    drawnow;
                end
            end 
        end

        % Menu selected function: AtualizarhistogramaMenu
        function AtualizarautomaticamenteMenuSelected(app, event)
            if(strcmp(app.AtualizarhistogramaMenu.Checked, 'off'))
                app.AtualizarhistogramaMenu.Checked = 'on';
            else
                app.AtualizarhistogramaMenu.Checked = 'off';
            end
        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            value = app.FPSEditField.Value;
            if(app.BoolCameraConectada)
                app.ThermalCamera.Fps = value;
            else
                app.ThermalCamera.FrameRate = value;
            end
        end

        % Button pushed function: VisualizarreconstruoButton
        function VisualizarreconstruoButtonPushed(app, event)
            tamanho = size(app.Coeficientes{1}, 3);         %TODO
            if(app.Coeficientes{end}.Dividir)
                funcao = @(x, tamanho)((sum(app.Coeficientes{1}.*MatrizV(x, tamanho),3).*(x < app.MatrizTempo)) +...
                                                  (sum(app.Coeficientes{2}.*MatrizV(x, tamanho), 3).*(x >= app.MatrizTempo)));
            else
                funcao = @(x, tamanho)(sum(app.Coeficientes{1}.*MatrizV(x, tamanho), 3));
            end
            if(~strcmp(app.Coeficientes{end}.Modelo, 'Polinômio'))
                app.FuncaoReconst = @(x, tamanho)exp(funcao(log(double(x+1)), tamanho));
            else
                app.FuncaoReconst = @(x, tamanho)funcao(x, tamanho);
            end
            app.FigureReconstrucao = figure(2);
            app.AxesReconstrucao = axes(app.FigureReconstrucao);
            app.ImagemReconstrucao = imshow(mat2gray(app.FuncaoReconst(app.FrameEditField.Value, tamanho)), 'Parent', app.AxesReconstrucao, 'Border', 'tight');
            truesize(gcf, [360 480]);
            if(app.Coeficientes{end}.Dividir)
                nElementos = (size(app.Coeficientes{1}, 3)+1)*2;
                elementos = cell(1, nElementos);
                data = cell(1,nElementos);
                for i=1:nElementos/2-1
                    elementos{i} = strcat('a', int2str(i), ':1');
                    data{i}.Tipo = 'Matriz';
                    data{i}.IndiceCell = 1;
                    data{i}.IndiceMatriz = i;
                end
                elementos{nElementos/2} = 'r^2_1';
                data{nElementos/2}.Tipo = 'Erro';
                data{nElementos/2}.IndiceCell = 1;
                for i=1:nElementos/2-1
                    elementos{(nElementos/2)+i} = strcat('a', int2str(i), ':2');
                    data{(nElementos/2)+i}.Tipo = 'Matriz';
                    data{(nElementos/2)+i}.IndiceCell = 2;
                    data{(nElementos/2)+i}.IndiceMatriz = i;
                end
                elementos{nElementos} = 'r^2_2';
                data{nElementos}.Tipo = 'Erro';
                data{nElementos}.IndiceCell = 2;
                app.MatrizDropDown.Items = elementos;
                app.MatrizDropDown.ItemsData = data;
            else
                nElementos = size(app.Coeficientes{1}, 3)+1;
                elementos = cell(1, nElementos);
                data = cell(1,nElementos);
                for i=1:nElementos-1
                    elementos{i} = strcat('a', int2str(i));
                    data{i}.Tipo = 'Matriz';
                    data{i}.IndiceCell = 1;
                    data{i}.IndiceMatriz = i;
                end
                elementos{nElementos} = 'r^2';
                data{nElementos}.Tipo = 'Erro';
                data{nElementos}.IndiceCell = 1;
                app.MatrizDropDown.Items = elementos;
                app.MatrizDropDown.ItemsData = data;
            end
            app.MostrarTemperaturaCheckBox.Enable = 'on';
            app.PlotarButton.Enable = 'on';
            app.FrameEditField.Enable = 'on';
            app.VisualizarCheckBox.Enable = 'on';
            app.TamanhooriginalEditField.Enable = 'on';
            app.VisualizarMatrizButton.Enable = 'on';
            app.PaletadecoresDropDown.Enable = 'on';
        end

        % Button pushed function: DerivarButton
        function DerivarButtonPushed(app, event)
            coeftmp = app.Coeficientes;
            app.Coeficientes = cell(size(coeftmp));
            for i=1:size(coeftmp(1:end-1), 2)
                app.Coeficientes{i} = zeros(size(coeftmp{i}, 1), size(coeftmp{i}, 2), size(coeftmp{i}, 3)-1);
                tamanhoder = size(app.Coeficientes{i}, 3);
                for j=1:tamanhoder
                    app.Coeficientes{i}(:,:,j) = coeftmp{i}(:,:,j)*(tamanhoder-j+1);
                end
            end
            app.Coeficientes{end} = coeftmp{end};
            app.Coeficientes{end}.GrauDerivada = app.Coeficientes{end}.GrauDerivada + 1;
            app.CaminhoSalvar();
            Coeficientes =  app.Coeficientes;
            rquadrado = app.rquadrado;
            matriztempo = app.MatrizTempo;
            save(app.CaminhoDerivada, 'Coeficientes', 'rquadrado', 'matriztempo');
            clear('Coeficientes', 'rquadrado', 'matriztempo');
            app.DerivadaAtualLabel.Text = strcat('Derivada atual:', int2str(app.Coeficientes{end}.GrauDerivada), 'ª');
            img = app.AxesReconstrucao.Children(end);
            reconst = app.FuncaoReconst(app.TempoEditField.Value, size(app.Coeficientes{1}, 3));
            set(img, 'CData', reconst);
            set(img.Parent, 'CLim', [min(reconst(:)) max(reconst(:))]);
        end

        % Value changed function: TempoEditField
        function TempoEditFieldValueChanged(app, event)
            value = app.TempoEditField.Value;
            %app.FrameEditField.Value = value;
%             img = app.AxesReconstrucao.Children(end);
%             reconst = app.FuncaoReconst(value, size(app.Coeficientes{1}, 3));
%             reconst = app.Filtros(reconst);
%             set(img, 'CData', reconst);
%             set(img.Parent, 'CLim', [min(reconst(:)) max(reconst(:))]);
            app.AtualizarImagem();
        end

        % Button pushed function: CarregarmatrizButton
        function CarregarmatrizButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.*'},'Selecionar arquivo .mat');
            if(file~=0)
                str = load(strcat(path, file));
                app.Coeficientes = str.Coeficientes;
                if(app.Coeficientes{end}.Dividir)
                    app.MatrizTempo = str.matriztempo;
                end
                app.rquadrado = str.rquadrado;
                app.DerivarButton.Enable = 'on';
                app.VisualizarreconstruoButton.Enable = 'on';
                if(str.Coeficientes{end}.GrauDerivada == 0)
                    app.DerivadaAtualLabel.Text = 'Derivada atual: 0';
                else
                    app.DerivadaAtualLabel.Text = strcat('Derivada atual:', int2str(str.Coeficientes{end}.GrauDerivada), 'ª');
                end
            end
            x = linspace(0, app.Coeficientes{end}.Tempo, app.Coeficientes{end}.nFrames)';
            if(~strcmp(app.Coeficientes{end}.Modelo, 'Polinômio'))
                x = log(x+1);
            end
            tamanho = size(app.Coeficientes{1}, 3);
            app.V = [];
            app.V(:, 1, tamanho) = ones(length(x), 1);
            for i=tamanho-1:-1:1
                app.V(:, 1, i) = app.V(:, i+1).*x;
            end
            app.VisualizarreconstruoButtonPushed();
            
            app.MostrarTemperaturaCheckBox.Enable = 'on';
            app.BotaoPlayPause.Enable = 'on';
            app.RepetirCheckBox.Enable = 'on';
            app.PlotarButton.Enable = 'on';
        end

        % Value changed function: DesvioPadraoEditField, 
        % FiltroDropDown, TamanhoMascaraMediana
        function VerificarMascara(app, event)
            if(event.Source == app.TamanhoMascaraMediana || event.Source == app.TamanhoMascaraGauss)
                value = event.Source.Value;
                if(mod(value, 2) == 0)
                    warndlg('A máscara deve ter tamanho ímpar!');
                    event.Source.Value = event.Source.Value+1;
                end
            end
            app.AtualizarImagem();
        end

        % Value changed function: HistogramaCheckBox
        function HistogramaCheckBoxValueChanged(app, event)
            value = app.HistogramaCheckBox.Value;
            fig = gcf;
            if(value)
                fig.WindowButtonDownFcn = @app.HistogramaButtonDownCallback;
            else
                delete(app.RetanguloHistograma);
                fig.WindowButtonDownFcn = [];
            end
        end

        % Value changed function: FPSdisponveisDropDown
        function FPSdisponveisDropDownValueChanged(app, event)
            value = app.FPSdisponveisDropDown.Value;
            app.ThermalCamera.FrameRateIndex = value;
        end

        % Value changed function: ResoluesDisponveisDropDown
        function ResoluesDisponveisDropDownValueChanged(app, event)
            value = app.ResoluesDisponveisDropDown.Value;
            app.ThermalCamera.SelectedResolutionIndex = value;
            pause(0.5);
            img = double(app.ThermalCamera.ThermalImage.ImageArray);
            tamanho = size(img);
            ax = app.Imagem.Parent;
            ax.XLim(2) = tamanho(2);
            ax.YLim(2) = tamanho(1);
        end

        % Value changed function: CoordenadaXEditField
        function CoordenadaXEditFieldValueChanged(app, event)
            value = app.CoordenadaXEditField.Value;
            fig = gcf;
            if(fig.Number == 1 || fig.Number == 2)
                fig.UserData.Plots{fig.UserData.PontoAtual}.XData = value;
                p = System.Drawing.Point(fig.UserData.Plots{fig.UserData.PontoAtual}.XData-1, fig.UserData.Plots{fig.UserData.PontoAtual}.YData-1);
                temp = app.ThermalCamera.ThermalImage.GetValueAt(p).Value;
                app.TemperaturaEditField.Value = double(temp);
            end
        end

        % Value changed function: CoordenadaYEditField
        function CoordenadaYEditFieldValueChanged(app, event)
            value = app.CoordenadaYEditField.Value;
            fig = gcf;
            if(fig.Number == 1 || fig.Number == 2)
                fig.UserData.Plots{fig.UserData.PontoAtual}.YData = value;
                p = System.Drawing.Point(fig.UserData.Plots{fig.UserData.PontoAtual}.XData-1, fig.UserData.Plots{fig.UserData.PontoAtual}.YData-1);
                temp = app.ThermalCamera.ThermalImage.GetValueAt(p).Value;
                app.TemperaturaEditField.Value = double(temp);
            end
            
        end

        % Button pushed function: CopiarpontosButton
        function CopiarpontosButtonPushed(app, event)
            fig = gcf;
            if(fig.Number == 1)
                figura2 =  figure(2);
                figura2.UserData = fig.UserData;
                pontos = fig.Children.Children(1:end-1);
                copyobj(pontos, figura2.Children);
                for i=1:length(pontos)
                    figura2.UserData.Plots{i} = figura2.Children.Children(i);
                end
            elseif(fig.Number == 2)
                figura1 =  figure(1);
                figura1.UserData = fig.UserData;
                pontos = fig.Children.Children(1:end-1);
                copyobj(pontos, figura1.Children);
                for i=1:length(pontos)
                    figura1.UserData.Plots{i} = figura1.Children.Children(i);
                end
            end
        end

        % Button pushed function: BotaoMediana
        function BotaoMedianaButtonPushed(app, event)
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {'Mediana'};
                struct.Nome = 'Mediana';
                struct.TamanhoMascara = app.TamanhoMascaraMediana.Value;
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = 'Mediana';
                struct.Nome = 'Mediana';
                struct.TamanhoMascara = app.TamanhoMascaraMediana.Value;
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoGauss
        function BotaoGaussButtonPushed(app, event)
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {'Gaussiana'};
                struct.Nome = 'Gaussiana';
                struct.TamanhoMascara = app.TamanhoMascaraGauss.Value;
                struct.DesvioPadrao = app.DesvioPadraoEditField.Value;
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = 'Gaussiana';
                struct.Nome = 'Gaussiana';
                struct.TamanhoMascara = app.TamanhoMascaraGauss.Value;
                struct.DesvioPadrao = app.DesvioPadraoEditField.Value;
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoBordas
        function BotaoBordasButtonPushed(app, event)
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {app.FiltroDropDown.Value};
                struct.Nome = 'Bordas';
                struct.Tipo = app.FiltroDropDown.Value;
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = app.FiltroDropDown.Value;
                struct.Nome = 'Bordas';
                struct.Tipo = app.FiltroDropDown.Value;
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoDilatacao, BotaoErosao
        function ErosaoDilatacaoButtonPushed(app, event)
            if(event.Source == app.BotaoErosao)
                tipo = app.ElementoErosaoDropDown.Value;
            else
                tipo = app.ElementoDilatacaoDropDown.Value;
            end
            switch tipo
                case 'Diamante'
                    saida = inputdlg({'Dimensão do centro à ponta do diamante:'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'5'});
                    SE = strel('diamond', str2double(saida{1}));
                case 'Disco'
                    saida = inputdlg({'Raio do disco:'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'5'});
                    SE = strel('disk', str2double(saida{1}), 0);
                case 'Linha'
                    saida = inputdlg({'Dimensão da linha:', 'Ângulo da linha (graus):'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'5','0'});
                    SE = strel('line', str2double(saida{1}), str2double(saida{2}));
                case 'Octágono'
                    saida = inputdlg({'Dimensão do centro à ponta do octágno (multiplo de 3):'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'9'});
                    SE = strel('octagon', str2double(saida{1}));
                case 'Retângulo'
                    saida = inputdlg({'Dimensão da altura:', 'Dimensão da largura:'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'5','10'});
                    SE = strel('rectangle', [str2double(saida{1}) str2double(saida{2})]);
                case 'Quadrado'
                    saida = inputdlg({'Dimensão do lado:'}, ...
                                     'Entrada', ...
                                     [1 35], ...
                                     {'10'});
                    SE = strel('square', str2double(saida{1}));
            end
            if(event.Source == app.BotaoErosao)
                operacao = 'Erosão';
            else
                operacao = 'Dilatação';
            end
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {strcat(operacao, ': ', app.ElementoErosaoDropDown.Value)};
                struct.Nome = operacao;
                struct.NomeElemento = app.ElementoErosaoDropDown.Value;
                struct.IndiceFiltro = 1;
                struct.ElementoEstruturante = SE;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = strcat(operacao, ': ', app.ElementoErosaoDropDown.Value);
                struct.Nome = operacao;
                struct.NomeElemento = app.ElementoErosaoDropDown.Value;
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                struct.ElementoEstruturante = SE;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoDeletarFiltro
        function BotaoDeletarFiltroButtonPushed(app, event)
            if(~isempty(app.FiltrosatuaisListBox.Value))
                indice = app.FiltrosatuaisListBox.Value.IndiceFiltro;
                tmpItems = app.FiltrosatuaisListBox.Items;
                tmpItemsData = app.FiltrosatuaisListBox.ItemsData;
                tmpItems{indice} = [];
                tmpItemsData{indice} = [];
                app.FiltrosatuaisListBox.Items = tmpItems(~cellfun('isempty', tmpItems));
                app.FiltrosatuaisListBox.ItemsData = tmpItemsData(~cellfun('isempty', tmpItemsData));
                for i=indice:length(app.FiltrosatuaisListBox.ItemsData)
                    app.FiltrosatuaisListBox.ItemsData{i}.IndiceFiltro = i;
                end
                if(length(app.FiltrosatuaisListBox.ItemsData) < indice && ~isempty(app.FiltrosatuaisListBox.ItemsData))
                    app.FiltrosatuaisListBox.Value = app.FiltrosatuaisListBox.ItemsData{indice-1};
                elseif(~isempty(app.FiltrosatuaisListBox.ItemsData))
                    app.FiltrosatuaisListBox.Value = app.FiltrosatuaisListBox.ItemsData{indice};
                end
                if(isempty(app.FiltrosatuaisListBox.ItemsData))
                    app.BoolAplicarFiltros = false;
                end
                app.AtualizarImagem();
            end
        end

        % Button pushed function: BotaoMoverPraCima
        function BotaoMoverPraCimaButtonPushed(app, event)
            indice = app.FiltrosatuaisListBox.Value.IndiceFiltro;
            if(indice > 1)
                app.FiltrosatuaisListBox.ItemsData{indice}.IndiceFiltro = indice-1;
                app.FiltrosatuaisListBox.ItemsData{indice-1}.IndiceFiltro = indice;
                app.FiltrosatuaisListBox.Items([indice indice-1]) = app.FiltrosatuaisListBox.Items([indice-1 indice]);
                app.FiltrosatuaisListBox.ItemsData([indice indice-1]) = app.FiltrosatuaisListBox.ItemsData([indice-1 indice]);
                app.FiltrosatuaisListBox.Value = app.FiltrosatuaisListBox.ItemsData{indice-1};
            end
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoMoverPraBaixo
        function BotaoMoverPraBaixoButtonPushed(app, event)
            indice = app.FiltrosatuaisListBox.Value.IndiceFiltro;
            if(indice < length(app.FiltrosatuaisListBox.ItemsData))
                app.FiltrosatuaisListBox.ItemsData{indice}.IndiceFiltro = indice+1;
                app.FiltrosatuaisListBox.ItemsData{indice+1}.IndiceFiltro = indice;
                app.FiltrosatuaisListBox.Items([indice indice+1]) = app.FiltrosatuaisListBox.Items([indice+1 indice]);
                app.FiltrosatuaisListBox.ItemsData([indice indice+1]) = app.FiltrosatuaisListBox.ItemsData([indice+1 indice]);
                app.FiltrosatuaisListBox.Value = app.FiltrosatuaisListBox.ItemsData{indice+1};
            end
            app.AtualizarImagem();
        end

        % Button pushed function: BotaoEqualizarHistograma
        function BotaoEqualizarHistogramaValueChanged(app, event)
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {'Eq. de Histograma'};
                struct.Nome = 'Histograma';
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = 'Eq. de Histograma';
                struct.Nome = 'Histograma';
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Value changed function: MatrizDropDown
        function MatrizDropDownValueChanged(app, event)
            value = app.MatrizDropDown.Value;
            nElementos = (size(app.Coeficientes{1}, 3)+1)*2;
        end

        % Button pushed function: BotaoGamma
        function BotaoGammaButtonPushed(app, event)
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {strcat('Gamma λ=', num2str(app.ValorGamma.Value))};
                struct.Nome = 'Gamma';
                struct.Gamma = app.ValorGamma.Value;
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = strcat('Gamma λ=', num2str(app.ValorGamma.Value));
                struct.Nome = 'Gamma';
                struct.Gamma = app.ValorGamma.Value;
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
        end

        % Value changed function: AplicarReconstrucaoCheckBox, 
        % AplicarVideoCheckBox
        function AplicarFiltroCheckBoxValueChanged(app, event)
            value = app.AplicarVideoCheckBox.Value;
            app.AtualizarImagem();
        end

        % Value changed function: CompararpontosCheckBox
        function CompararpontosCheckBoxValueChanged(app, event)
            value = app.CompararpontosCheckBox.Value;
            fig = gcf;
            if(fig.Number == 1 || fig.Number == 2)
                app.MostrarTemperaturaCheckBox.Value = 0;
                app.MostrarTemperaturaCheckBoxValueChanged();
                app.VisualizarCheckBox.Value = 0;
                app.VisualizarCheckBoxValueChanged();
                app.LinhasCheckBox.Value = 0;
                app.LinhasCheckBoxValueChanged();
                app.HistogramaCheckBox.Value = 0;
                app.HistogramaCheckBoxValueChanged();
                fig.UserData.PontoAtual = 1;
                if(value)
                    f = msgbox('Selecione o ponto de referência na imagem térmica');
                    waitfor(f);
                    fig.WindowButtonUpFcn = @app.CompararButtonUpCallback;
                else
                    
                end
            else
                app.CompararpontosCheckBox.Value = 0;
            end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            fig = gcf;
            if((fig.Number == 1 || fig.Number == 2) && (length(fig.UserData.Plots) == 1))
                if(fig.Number == 1)
                    MaxDiff(app.ThermalCamera, round(fig.UserData.Plots{1}.XData), round(fig.UserData.Plots{1}.YData), app.DividirCheckBox.Value);
                else
                    if(app.Coeficientes{end}.Dividir)
                        MaxDiffTSR(app.Coeficientes, round(fig.UserData.Plots{1}.XData), round(fig.UserData.Plots{1}.YData), app.MatrizTempo);
                    else
                        MaxDiffTSR(app.Coeficientes, round(fig.UserData.Plots{1}.XData), round(fig.UserData.Plots{1}.YData));
                    end
                 end
            end
        end

        % Menu selected function: BMenu, GBMenu, KBMenu, MBMenu
        function TamanhoMenuSelected(app, event)
            switch event.Source
                case app.BMenu
                    app.BMenu.Checked = 'on';
                    app.KBMenu.Checked = 'off';
                    app.MBMenu.Checked = 'off';
                    app.GBMenu.Checked = 'off';
                    label = 'B';
                case app.KBMenu
                    app.BMenu.Checked = 'off';
                    app.KBMenu.Checked = 'on';
                    app.MBMenu.Checked = 'off';
                    app.GBMenu.Checked = 'off';
                    label = 'KB';
                case app.MBMenu
                    app.BMenu.Checked = 'off';
                    app.KBMenu.Checked = 'off';
                    app.MBMenu.Checked = 'on';
                    app.GBMenu.Checked = 'off';
                    label = 'MB';
                case app.GBMenu
                    app.BMenu.Checked = 'off';
                    app.KBMenu.Checked = 'off';
                    app.MBMenu.Checked = 'off';
                    app.GBMenu.Checked = 'on';
                    label = 'GB';
            end
            app.MBLabel.Text = label;
            app.MBLabel_2.Text = label;
            app.TamanhooriginalEditField.Value = app.ConverterTamanho(app.TamanhoArquivo.bytes);
            app.TamanhodamatrizEditField.Value = app.ConverterTamanho(app.TamanhoMatriz.bytes);
        end

        % Menu selected function: DistanciaMenu, EmissividadeMenu, 
        % TemperaturaAtmosfericaMenu, TemperaturaDeReferenciaMenu, 
        % TemperaturaOticaExternaMenu, TemperaturaRefletidaMenu, 
        % TransmissaoAtmosfericaMenu, TransmissaoOticaExternaMenu, 
        % UmidadeRelativaMenu
        function MudarPropriedade(app, event)
            parametros = app.ThermalCamera.ThermalImage.ThermalParameters;
            try
                switch event.Source
                    case app.DistanciaMenu
                        saida = inputdlg({'Entre a distância do objeto:'},'Entrada', [1 35] , {num2str(parametros.Distance)});
                        if(~isempty(saida))
                            parametros.Distance = str2double(saida{1});
                        end
                    case app.EmissividadeMenu
                        saida = inputdlg({'Entre a emissividade do objeto:'},'Entrada', [1 35] , {num2str(parametros.Emissivity)});
                        if(~isempty(saida))
                            parametros.Emissivity = str2double(saida{1});
                        end
                    case app.TemperaturaAtmosfericaMenu
                        saida = inputdlg({'Entre a temperatura atmosférica:'},'Entrada', [1 35] , {num2str(parametros.AtmosphericTemperature)});
                        if(~isempty(saida))
                            parametros.AtmosphericTemperature = str2double(saida{1});
                        end
                    case app.TemperaturaDeReferenciaMenu
                        saida = inputdlg({'Entre a temperatura de referência:'},'Entrada', [1 35] , {num2str(parametros.ReferenceTemperature)});
                        if(~isempty(saida))
                            parametros.ReferenceTemperature = str2double(saida{1});
                        end
                    case app.TemperaturaRefletidaMenu
                        saida = inputdlg({'Entre a temperatura refletida:'},'Entrada', [1 35] , {num2str(parametros.ReflectedTemperature)});
                        if(~isempty(saida))
                            parametros.ReflectedTemperature = str2double(saida{1});
                        end
                    case app.TemperaturaOticaExternaMenu
                        saida = inputdlg({'Entre a temperatura ótica externa:'},'Entrada', [1 35] , {num2str(parametros.ExternalOpticsTemperature)});
                        if(~isempty(saida))
                            parametros.ExternalOpticsTemperature = str2double(saida{1});
                        end
                    case app.TransmissaoAtmosfericaMenu
                        saida = inputdlg({'Entre a transmissão atmosférica:'},'Entrada', [1 35] , {num2str(parametros.Transmission)});
                        if(~isempty(saida))
                            parametros.Transmission = str2double(saida{1});
                        end
                    case app.TransmissaoOticaExternaMenu
                        saida = inputdlg({'Entre a transmissão ótica externa:'},'Entrada', [1 35] , {num2str(parametros.ExternalOpticsTransmission)});
                        if(~isempty(saida))
                            parametros.ExternalOpticsTransmission = str2double(saida{1});
                        end
                    case app.UmidadeRelativaMenu
                        saida = inputdlg({'Entre a umidade relativa:'},'Entrada', [1 35] , {num2str(parametros.RelativeHumidity)});
                        if(~isempty(saida))
                            parametros.RelativeHumidity = str2double(saida{1});
                        end
                end
            catch ex
                f = errordlg({'Valor inválido!'});
                
                waitfor(f);
            end
        end

        % Button pushed function: AdicionarAutomatico, 
        % AdicionarIntervalo, AdicionarLimiar
        function AdicionarMinimoValueChanged(app, event)
            switch event.Source
                case app.AdicionarLimiar
                    texto = {'Entre com a intensidade do limiar:'};
                    titulo = 'Intensidade do limiar.';
                    dims = [1 35];
                    retorno = inputdlg(texto,titulo,dims);
                    nome = strcat('Limiar:', num2str(retorno{1}));
                    struct.Tipo = 'Limiar';
                case app.AdicionarIntervalo
                    texto = {'Entre com a intensidade mínima do limiar:', 'Entre com a intensidade mínima do limiar:'};
                    titulo = 'Intensidade máxima do limiar.';
                    dims = [1 35];
                    retorno = inputdlg(texto,titulo,dims);
                    nome = strcat('Limiar: [', num2str(retorno{1}), ' ', num2str(retorno{2}), ']');
                    struct.Tipo = 'Intervalo';
                case app.AdicionarAutomatico
                    nome = 'Limiar: Automático';
                    retorno = 0;
                    struct.Tipo = 'Auto';
            end
            
            if(isempty(app.FiltrosatuaisListBox.Items))
                app.FiltrosatuaisListBox.Items = {nome};
                pause(0.5); %Sem esse pause, o automático quebra os filtros. ?????
                struct.Valor = str2double(retorno);
                struct.Nome = 'Limiar';
                struct.IndiceFiltro = 1;
                app.FiltrosatuaisListBox.ItemsData = {struct};
            else
                app.FiltrosatuaisListBox.Items{end+1} = nome;
                pause(0.5); %????????
                struct.Valor = str2double(retorno);
                struct.Nome = 'Limiar';
                struct.IndiceFiltro = app.FiltrosatuaisListBox.ItemsData{end}.IndiceFiltro+1;
                app.FiltrosatuaisListBox.ItemsData{end+1} = struct;
            end
            app.BoolAplicarFiltros = true;
            app.AtualizarImagem();
            
            
        end

        % Button pushed function: SelecionarButton
        function RegiaoDeAjusteButtonPushed(app, event)
            app.AtualizarImagem();
            fig = gcf;
            fig.WindowButtonDownFcn = @app.AjustarRegiaoButtonDownCallback;
        end

        % Value changed function: RegiodeajusteCheckBox
        function RegiodeajusteCheckBoxValueChanged(app, event)
            value = app.RegiodeajusteCheckBox.Value;
            app.ThermalCamera.ThermalImage.Scale.AutoAdjustRegion.IsEnabled = value;
            if(value)
                app.SelecionarButton.Enable = 'on';
            else
                app.SelecionarButton.Enable = 'off';
            end
        end

        % Value changed function: FiltrarCheckBox
        function FiltrarCheckBoxValueChanged(app, event)
            value = app.FiltrarCheckBox.Value;
            if(value)
                app.TamanhodofiltroEditField.Enable = 'on';
            else
                app.TamanhodofiltroEditField.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ArquivoMenu = uimenu(app.UIFigure);
            app.ArquivoMenu.Text = 'Arquivo';
            app.AbrirMenu = uimenu(app.ArquivoMenu);
            app.AbrirMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirMenuSelected, true);
            app.AbrirMenu.Text = 'Abrir';
            app.SairMenu = uimenu(app.ArquivoMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SairMenu.Text = 'Sair';
            app.CmeraMenu = uimenu(app.UIFigure);
            app.CmeraMenu.Text = 'Câmera...';
            app.ProcurarCmerasMenu = uimenu(app.CmeraMenu);
            app.ProcurarCmerasMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcurarCmerasMenuSelected, true);
            app.ProcurarCmerasMenu.Text = 'Procurar Câmeras';
            app.DesconectarcmeraMenu = uimenu(app.CmeraMenu);
            app.DesconectarcmeraMenu.MenuSelectedFcn = createCallbackFcn(app, @DesconectarcmeraMenuSelected, true);
            app.DesconectarcmeraMenu.Text = 'Desconectar câmera';
            app.OpesMenu = uimenu(app.UIFigure);
            app.OpesMenu.Text = 'Opções';
            app.ParmetrosTermicosMenu = uimenu(app.OpesMenu);
            app.ParmetrosTermicosMenu.Text = 'Parâmetros Térmicos';
            app.DistanciaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.DistanciaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.DistanciaMenu.Text = 'Distância';
            app.EmissividadeMenu = uimenu(app.ParmetrosTermicosMenu);
            app.EmissividadeMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.EmissividadeMenu.Text = 'Emissividade';
            app.TemperaturaAtmosfericaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TemperaturaAtmosfericaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TemperaturaAtmosfericaMenu.Text = 'Temperatura atmosférica';
            app.TemperaturaRefletidaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TemperaturaRefletidaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TemperaturaRefletidaMenu.Text = 'Temperatura refletida';
            app.TemperaturaOticaExternaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TemperaturaOticaExternaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TemperaturaOticaExternaMenu.Text = 'Temperatura ótica externa';
            app.TransmissaoOticaExternaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TransmissaoOticaExternaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TransmissaoOticaExternaMenu.Text = 'Transmissão ótica externa';
            app.TemperaturaDeReferenciaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TemperaturaDeReferenciaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TemperaturaDeReferenciaMenu.Text = 'Temperatura de referência';
            app.UmidadeRelativaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.UmidadeRelativaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.UmidadeRelativaMenu.Text = 'Umidade relativa';
            app.TransmissaoAtmosfericaMenu = uimenu(app.ParmetrosTermicosMenu);
            app.TransmissaoAtmosfericaMenu.MenuSelectedFcn = createCallbackFcn(app, @MudarPropriedade, true);
            app.TransmissaoAtmosfericaMenu.Text = 'Transmissão atmosférica';
            app.CalcularEmissividadeMenu = uimenu(app.OpesMenu);
            app.CalcularEmissividadeMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcularEmissividadeMenuSelected, true);
            app.CalcularEmissividadeMenu.Text = 'Calcular emissividade';
            app.UnidadesMenu = uimenu(app.OpesMenu);
            app.UnidadesMenu.Text = 'Unidades';
            app.TamanhodoarquivoMenu = uimenu(app.UnidadesMenu);
            app.TamanhodoarquivoMenu.Text = 'Tamanho do arquivo';
            app.BMenu = uimenu(app.TamanhodoarquivoMenu);
            app.BMenu.MenuSelectedFcn = createCallbackFcn(app, @TamanhoMenuSelected, true);
            app.BMenu.Text = 'B';
            app.KBMenu = uimenu(app.TamanhodoarquivoMenu);
            app.KBMenu.MenuSelectedFcn = createCallbackFcn(app, @TamanhoMenuSelected, true);
            app.KBMenu.Text = 'KB';
            app.MBMenu = uimenu(app.TamanhodoarquivoMenu);
            app.MBMenu.MenuSelectedFcn = createCallbackFcn(app, @TamanhoMenuSelected, true);
            app.MBMenu.Checked = 'on';
            app.MBMenu.Text = 'MB';
            app.GBMenu = uimenu(app.TamanhodoarquivoMenu);
            app.GBMenu.MenuSelectedFcn = createCallbackFcn(app, @TamanhoMenuSelected, true);
            app.GBMenu.Text = 'GB';
            app.TemperaturaMenu = uimenu(app.UnidadesMenu);
            app.TemperaturaMenu.Text = 'Temperatura';
            app.CelsiusMenu = uimenu(app.TemperaturaMenu);
            app.CelsiusMenu.MenuSelectedFcn = createCallbackFcn(app, @CelsiusMenuSelected, true);
            app.CelsiusMenu.Checked = 'on';
            app.CelsiusMenu.Text = 'Celsius';
            app.KelvinMenu = uimenu(app.TemperaturaMenu);
            app.KelvinMenu.MenuSelectedFcn = createCallbackFcn(app, @KelvinMenuSelected, true);
            app.KelvinMenu.Text = 'Kelvin';
            app.FahrenheitMenu = uimenu(app.TemperaturaMenu);
            app.FahrenheitMenu.MenuSelectedFcn = createCallbackFcn(app, @FahrenheitMenuSelected, true);
            app.FahrenheitMenu.Text = 'Fahrenheit';
            app.VisualizaoMenu = uimenu(app.OpesMenu);
            app.VisualizaoMenu.Text = 'Visualização';
            app.TemperaturaMenu_2 = uimenu(app.VisualizaoMenu);
            app.TemperaturaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TemperaturaMenu_2Selected, true);
            app.TemperaturaMenu_2.Text = 'Temperatura';
            app.SinalMenu = uimenu(app.VisualizaoMenu);
            app.SinalMenu.MenuSelectedFcn = createCallbackFcn(app, @SinalMenuSelected, true);
            app.SinalMenu.Text = 'Sinal';
            app.EqdeHistogramaMenu = uimenu(app.VisualizaoMenu);
            app.EqdeHistogramaMenu.MenuSelectedFcn = createCallbackFcn(app, @EqdeHistogramaMenuSelected, true);
            app.EqdeHistogramaMenu.Text = 'Eq. de Histograma';
            app.AtualizarhistogramaMenu = uimenu(app.VisualizaoMenu);
            app.AtualizarhistogramaMenu.MenuSelectedFcn = createCallbackFcn(app, @AtualizarautomaticamenteMenuSelected, true);
            app.AtualizarhistogramaMenu.Text = 'Atualizar histograma';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GravaoReproduoTab = uitab(app.TabGroup);
            app.GravaoReproduoTab.Title = 'Gravação/Reprodução';
            app.GravaoPanel = uipanel(app.GravaoReproduoTab);
            app.GravaoPanel.Title = 'Gravação';
            app.IniciargravaoButton = uibutton(app.GravaoPanel, 'push');
            app.IniciargravaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciargravaoButtonPushed, true);
            app.IniciargravaoButton.Text = 'Iniciar gravação';
            app.ParargravaoButton = uibutton(app.GravaoPanel, 'push');
            app.ParargravaoButton.ButtonPushedFcn = createCallbackFcn(app, @ParargravaoButtonPushed, true);
            app.ParargravaoButton.Text = 'Parar gravação';
            app.FPSdisponveisDropDownLabel = uilabel(app.GravaoPanel);
            app.FPSdisponveisDropDownLabel.Text = 'FPS disponíveis';
            app.FPSdisponveisDropDown = uidropdown(app.GravaoPanel);
            app.FPSdisponveisDropDown.Items = {};
            app.FPSdisponveisDropDown.ValueChangedFcn = createCallbackFcn(app, @FPSdisponveisDropDownValueChanged, true);
            app.FPSdisponveisDropDown.Value = {};
            app.ResoluesDisponveisDropDownLabel = uilabel(app.GravaoPanel);
            app.ResoluesDisponveisDropDownLabel.Text = {'Resoluções'; 'Disponíveis'};
            app.ResoluesDisponveisDropDown = uidropdown(app.GravaoPanel);
            app.ResoluesDisponveisDropDown.Items = {};
            app.ResoluesDisponveisDropDown.ValueChangedFcn = createCallbackFcn(app, @ResoluesDisponveisDropDownValueChanged, true);
            app.ResoluesDisponveisDropDown.Value = {};
            app.ReproduoPanel = uipanel(app.GravaoReproduoTab);
            app.ReproduoPanel.Title = 'Reprodução';
            app.RepetirCheckBox = uicheckbox(app.ReproduoPanel);
            app.RepetirCheckBox.ValueChangedFcn = createCallbackFcn(app, @RepetirCheckBoxValueChanged, true);
            app.RepetirCheckBox.Text = 'Repetir';
            app.BotaoParar = uibutton(app.ReproduoPanel, 'push');
            app.BotaoParar.ButtonPushedFcn = createCallbackFcn(app, @BotaoPararPushed, true);
            app.BotaoParar.Icon = 'stop.png';
            app.BotaoParar.Text = '';
            app.FrameLabel = uilabel(app.ReproduoPanel);
            app.FrameLabel.Text = 'Frame:';
            app.FrameEditField = uieditfield(app.ReproduoPanel, 'numeric');
            app.FrameEditField.Limits = [1 Inf];
            app.FrameEditField.RoundFractionalValues = 'on';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.SalvarImagemButton = uibutton(app.ReproduoPanel, 'push');
            app.SalvarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarImagemButtonPushed, true);
            app.SalvarImagemButton.Text = 'Salvar Imagem';
            app.BotaoPlayPause = uibutton(app.ReproduoPanel, 'push');
            app.BotaoPlayPause.ButtonPushedFcn = createCallbackFcn(app, @BotaoPlayPausePushed, true);
            app.BotaoPlayPause.Icon = 'play.png';
            app.BotaoPlayPause.Text = '';
            app.FPSLabel = uilabel(app.ReproduoPanel);
            app.FPSLabel.Text = 'FPS:';
            app.FPSEditField = uieditfield(app.ReproduoPanel, 'numeric');
            app.FPSEditField.Limits = [1 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.FPSEditField.Value = 30;
            app.SalvarPanel = uipanel(app.GravaoReproduoTab);
            app.SalvarPanel.Title = 'Salvar...';
            app.SalvarvdeoemLabel = uilabel(app.SalvarPanel);
            app.SalvarvdeoemLabel.Text = 'Salvar vídeo em:';
            app.SalvarVideoEditField = uieditfield(app.SalvarPanel, 'text');
            app.SalvarVideoEditField.ValueChangedFcn = createCallbackFcn(app, @SalvarVideoEditFieldValueChanged, true);
            app.SalvarImagememLabel = uilabel(app.SalvarPanel);
            app.SalvarImagememLabel.Text = 'Salvar Imagem em:';
            app.SalvarImagemEditField = uieditfield(app.SalvarPanel, 'text');
            app.SalvarImagemEditField.ValueChangedFcn = createCallbackFcn(app, @SalvarImagemEditFieldValueChanged, true);
            app.BotaoMenuVideo = uibutton(app.SalvarPanel, 'push');
            app.BotaoMenuVideo.ButtonPushedFcn = createCallbackFcn(app, @BotaoMenuVideoButtonPushed, true);
            app.BotaoMenuVideo.Text = '...';
            app.BotaoMenuImagem = uibutton(app.SalvarPanel, 'push');
            app.BotaoMenuImagem.ButtonPushedFcn = createCallbackFcn(app, @BotaoMenuImagemButtonPushed, true);
            app.BotaoMenuImagem.Text = '...';
            app.AparnciaPanel = uipanel(app.GravaoReproduoTab);
            app.AparnciaPanel.Title = 'Aparência';
            app.PaletadecoresLabel = uilabel(app.AparnciaPanel);
            app.PaletadecoresLabel.Text = {'Paleta de '; 'cores'};
            app.PaletadecoresDropDown = uidropdown(app.AparnciaPanel);
            app.PaletadecoresDropDown.Items = {};
            app.PaletadecoresDropDown.ValueChangedFcn = createCallbackFcn(app, @PaletadecoresDropDownValueChanged, true);
            app.PaletadecoresDropDown.Value = {};
            app.SelecionarButton = uibutton(app.AparnciaPanel, 'push');
            app.SelecionarButton.ButtonPushedFcn = createCallbackFcn(app, @RegiaoDeAjusteButtonPushed, true);
            app.SelecionarButton.Enable = 'off';
            app.SelecionarButton.Text = 'Selecionar';
            app.RegiodeajusteCheckBox = uicheckbox(app.AparnciaPanel);
            app.RegiodeajusteCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegiodeajusteCheckBoxValueChanged, true);
            app.RegiodeajusteCheckBox.Text = 'Região de ajuste';
            app.AnliseTab = uitab(app.TabGroup);
            app.AnliseTab.Title = 'Análise';
            app.IsotermasPanel = uipanel(app.AnliseTab);
            app.IsotermasPanel.Title = 'Isotermas';
            app.AdicionarAcimaButton = uibutton(app.IsotermasPanel, 'push');
            app.AdicionarAcimaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarAcimaButtonPushed, true);
            app.AdicionarAcimaButton.Text = 'Adicionar Acima';
            app.AdicionarAbaixoButton = uibutton(app.IsotermasPanel, 'push');
            app.AdicionarAbaixoButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarAbaixoButtonPushed, true);
            app.AdicionarAbaixoButton.Text = 'Adicionar Abaixo';
            app.RemoverButton = uibutton(app.IsotermasPanel, 'push');
            app.RemoverButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverButtonPushed, true);
            app.RemoverButton.Text = 'Remover';
            app.REditFieldLabel = uilabel(app.IsotermasPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.IsotermasPanel, 'numeric');
            app.REditField.Limits = [0 255];
            app.GEditFieldLabel = uilabel(app.IsotermasPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.IsotermasPanel, 'numeric');
            app.GEditField.Limits = [0 255];
            app.BEditFieldLabel = uilabel(app.IsotermasPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.IsotermasPanel, 'numeric');
            app.BEditField.Limits = [0 255];
            app.AdicionarIntervaloButton = uibutton(app.IsotermasPanel, 'push');
            app.AdicionarIntervaloButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarIntervaloButtonPushed, true);
            app.AdicionarIntervaloButton.Text = 'Adicionar Intervalo';
            app.AparnciaDropDownLabel = uilabel(app.IsotermasPanel);
            app.AparnciaDropDownLabel.Text = 'Aparência';
            app.AparnciaDropDown = uidropdown(app.IsotermasPanel);
            app.AparnciaDropDown.Items = {};
            app.AparnciaDropDown.Value = {};
            app.CordecontrasteDropDownLabel = uilabel(app.IsotermasPanel);
            app.CordecontrasteDropDownLabel.Text = 'Cor de contraste';
            app.CordecontrasteDropDown = uidropdown(app.IsotermasPanel);
            app.CordecontrasteDropDown.Items = {};
            app.CordecontrasteDropDown.Value = {};
            app.TemperaturaPanel = uipanel(app.AnliseTab);
            app.TemperaturaPanel.Title = 'Temperatura...';
            app.CoordenadaXEditFieldLabel = uilabel(app.TemperaturaPanel);
            app.CoordenadaXEditFieldLabel.Text = 'Coordenada X';
            app.CoordenadaXEditField = uieditfield(app.TemperaturaPanel, 'numeric');
            app.CoordenadaXEditField.ValueChangedFcn = createCallbackFcn(app, @CoordenadaXEditFieldValueChanged, true);
            app.MostrarTemperaturaCheckBox = uicheckbox(app.TemperaturaPanel);
            app.MostrarTemperaturaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarTemperaturaCheckBoxValueChanged, true);
            app.MostrarTemperaturaCheckBox.Text = 'Mostrar Temperatura';
            app.CoordenadaYEditFieldLabel = uilabel(app.TemperaturaPanel);
            app.CoordenadaYEditFieldLabel.Text = 'Coordenada Y';
            app.CoordenadaYEditField = uieditfield(app.TemperaturaPanel, 'numeric');
            app.CoordenadaYEditField.ValueChangedFcn = createCallbackFcn(app, @CoordenadaYEditFieldValueChanged, true);
            app.TemperaturaEditFieldLabel = uilabel(app.TemperaturaPanel);
            app.TemperaturaEditFieldLabel.Text = 'Temperatura';
            app.TemperaturaEditField = uieditfield(app.TemperaturaPanel, 'numeric');
            app.TemperaturaEditField.Editable = 'off';
            app.PlotarButton = uibutton(app.TemperaturaPanel, 'push');
            app.PlotarButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarButtonPushed, true);
            app.PlotarButton.Text = 'Plotar';
            app.nPontosLabel = uilabel(app.TemperaturaPanel);
            app.nPontosLabel.Text = 'nº Pontos:';
            app.nPontosEditField = uieditfield(app.TemperaturaPanel, 'numeric');
            app.nPontosEditField.Limits = [1 Inf];
            app.nPontosEditField.ValueChangedFcn = createCallbackFcn(app, @nPontosEditFieldValueChanged, true);
            app.nPontosEditField.Value = 1;
            app.CopiarpontosButton = uibutton(app.TemperaturaPanel, 'push');
            app.CopiarpontosButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarpontosButtonPushed, true);
            app.CopiarpontosButton.Text = 'Copiar pontos';
            app.EstatsticasPanel = uipanel(app.AnliseTab);
            app.EstatsticasPanel.Title = 'Estatísticas';
            app.TemperaturaMximaEditFieldLabel = uilabel(app.EstatsticasPanel);
            app.TemperaturaMximaEditFieldLabel.Text = 'Temperatura Máxima';
            app.TemperaturaMaximaEditField = uieditfield(app.EstatsticasPanel, 'numeric');
            app.TemperaturaMaximaEditField.Editable = 'off';
            app.TemperaturaMnimaEditFieldLabel = uilabel(app.EstatsticasPanel);
            app.TemperaturaMnimaEditFieldLabel.Text = 'Temperatura Mínima';
            app.TemperaturaMinimaEditField = uieditfield(app.EstatsticasPanel, 'numeric');
            app.TemperaturaMinimaEditField.Editable = 'off';
            app.TemperaturaMdiaEditFieldLabel = uilabel(app.EstatsticasPanel);
            app.TemperaturaMdiaEditFieldLabel.Text = 'Temperatura Média';
            app.TemperaturaMediaEditField = uieditfield(app.EstatsticasPanel, 'numeric');
            app.TemperaturaMediaEditField.Editable = 'off';
            app.DesvioPadroEditFieldLabel = uilabel(app.EstatsticasPanel);
            app.DesvioPadroEditFieldLabel.Text = 'Desvio Padrão';
            app.DesvioPadroEditField = uieditfield(app.EstatsticasPanel, 'numeric');
            app.DesvioPadroEditField.Editable = 'off';
            app.CheckBoxTemperaturaMedia = uicheckbox(app.EstatsticasPanel);
            app.CheckBoxTemperaturaMedia.Text = '';
            app.CheckBoxTemperaturaMinima = uicheckbox(app.EstatsticasPanel);
            app.CheckBoxTemperaturaMinima.Text = '';
            app.CheckBoxTemperaturaMaxima = uicheckbox(app.EstatsticasPanel);
            app.CheckBoxTemperaturaMaxima.Text = '';
            app.CheckBoxTemperaturaDesvio = uicheckbox(app.EstatsticasPanel);
            app.CheckBoxTemperaturaDesvio.Text = '';
            app.PlotarLabel = uilabel(app.EstatsticasPanel);
            app.PlotarLabel.Text = 'Plotar...';
            app.VisualizarCheckBox = uicheckbox(app.EstatsticasPanel);
            app.VisualizarCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizarCheckBoxValueChanged, true);
            app.VisualizarCheckBox.Text = 'Visualizar';
            app.PlotarpropriedadesButton = uibutton(app.EstatsticasPanel, 'push');
            app.PlotarpropriedadesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarpropriedadesButtonPushed, true);
            app.PlotarpropriedadesButton.Text = 'Plotar propriedades';
            app.LinhasPanel = uipanel(app.AnliseTab);
            app.LinhasPanel.Title = 'Linhas';
            app.LinhasCheckBox = uicheckbox(app.LinhasPanel);
            app.LinhasCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinhasCheckBoxValueChanged, true);
            app.LinhasCheckBox.Text = 'Linhas';
            app.NumerodepontosEditFieldLabel = uilabel(app.LinhasPanel);
            app.NumerodepontosEditFieldLabel.Text = 'Numero de pontos';
            app.NumerodepontosEditField = uieditfield(app.LinhasPanel, 'numeric');
            app.NumerodepontosEditField.Limits = [1 Inf];
            app.NumerodepontosEditField.Value = 50;
            app.HistogramaPanel = uipanel(app.AnliseTab);
            app.HistogramaPanel.Title = 'Histograma';
            app.HistogramaCheckBox = uicheckbox(app.HistogramaPanel);
            app.HistogramaCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramaCheckBoxValueChanged, true);
            app.HistogramaCheckBox.Text = 'Histograma';
            app.CompararPanel = uipanel(app.AnliseTab);
            app.CompararPanel.Title = 'Comparar';
            app.CompararpontosCheckBox = uicheckbox(app.CompararPanel);
            app.CompararpontosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompararpontosCheckBoxValueChanged, true);
            app.CompararpontosCheckBox.Text = 'Comparar pontos';
            app.AplicarButton = uibutton(app.CompararPanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.DividirCheckBox = uicheckbox(app.CompararPanel);
            app.DividirCheckBox.Text = 'Dividir';
            app.FiltrosTab = uitab(app.TabGroup);
            app.FiltrosTab.Title = 'Filtros';
            app.MedianaPanel = uipanel(app.FiltrosTab);
            app.MedianaPanel.Title = 'Mediana';
            app.TamanhodamscaraEditFieldLabel = uilabel(app.MedianaPanel);
            app.TamanhodamscaraEditFieldLabel.Text = 'Tamanho da máscara';
            app.TamanhoMascaraMediana = uieditfield(app.MedianaPanel, 'numeric');
            app.TamanhoMascaraMediana.Limits = [1 Inf];
            app.TamanhoMascaraMediana.ValueChangedFcn = createCallbackFcn(app, @VerificarMascara, true);
            app.TamanhoMascaraMediana.Value = 3;
            app.BotaoMediana = uibutton(app.MedianaPanel, 'push');
            app.BotaoMediana.ButtonPushedFcn = createCallbackFcn(app, @BotaoMedianaButtonPushed, true);
            app.BotaoMediana.Text = 'Adicionar';
            app.GaussianoPanel = uipanel(app.FiltrosTab);
            app.GaussianoPanel.Title = 'Gaussiano';
            app.desviopadroEditFieldLabel = uilabel(app.GaussianoPanel);
            app.desviopadroEditFieldLabel.Text = 'desvio padrão';
            app.DesvioPadraoEditField = uieditfield(app.GaussianoPanel, 'numeric');
            app.DesvioPadraoEditField.LowerLimitInclusive = 'off';
            app.DesvioPadraoEditField.Limits = [0 Inf];
            app.DesvioPadraoEditField.ValueChangedFcn = createCallbackFcn(app, @VerificarMascara, true);
            app.DesvioPadraoEditField.Value = 1;
            app.BotaoGauss = uibutton(app.GaussianoPanel, 'push');
            app.BotaoGauss.ButtonPushedFcn = createCallbackFcn(app, @BotaoGaussButtonPushed, true);
            app.BotaoGauss.Text = 'Adicionar';
            app.TamanhodamscaraEditFieldLabel_2 = uilabel(app.GaussianoPanel);
            app.TamanhodamscaraEditFieldLabel_2.Text = 'Tamanho da máscara';
            app.TamanhoMascaraGauss = uieditfield(app.GaussianoPanel, 'numeric');
            app.TamanhoMascaraGauss.Limits = [1 Inf];
            app.TamanhoMascaraGauss.Value = 3;
            app.BordasPanel = uipanel(app.FiltrosTab);
            app.BordasPanel.Title = 'Bordas';
            app.BotaoBordas = uibutton(app.BordasPanel, 'push');
            app.BotaoBordas.ButtonPushedFcn = createCallbackFcn(app, @BotaoBordasButtonPushed, true);
            app.BotaoBordas.Text = 'Adicionar';
            app.FiltroDropDownLabel = uilabel(app.BordasPanel);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.BordasPanel);
            app.FiltroDropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'Log', 'Zero crossing', 'Canny', 'Canny aproximado'};
            app.FiltroDropDown.ItemsData = {'Sobel', 'Prewitt', 'Roberts', 'log', 'zerocross', 'Canny', 'approxcanny'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @VerificarMascara, true);
            app.FiltroDropDown.Value = 'Sobel';
            app.FiltrosatuaisListBoxLabel = uilabel(app.FiltrosTab);
            app.FiltrosatuaisListBoxLabel.Text = 'Filtros atuais';
            app.FiltrosatuaisListBox = uilistbox(app.FiltrosTab);
            app.FiltrosatuaisListBox.Items = {};
            app.FiltrosatuaisListBox.Value = {};
            app.ErosoPanel = uipanel(app.FiltrosTab);
            app.ErosoPanel.Title = 'Erosão';
            app.BotaoErosao = uibutton(app.ErosoPanel, 'push');
            app.BotaoErosao.ButtonPushedFcn = createCallbackFcn(app, @ErosaoDilatacaoButtonPushed, true);
            app.BotaoErosao.Text = 'Adicionar';
            app.ElementoestruturanteDropDownLabel = uilabel(app.ErosoPanel);
            app.ElementoestruturanteDropDownLabel.Text = 'Elemento estruturante';
            app.ElementoErosaoDropDown = uidropdown(app.ErosoPanel);
            app.ElementoErosaoDropDown.Items = {'Diamante', 'Disco', 'Linha', 'Octágono', 'Retângulo', 'Quadrado'};
            app.ElementoErosaoDropDown.Value = 'Diamante';
            app.DilataoPanel = uipanel(app.FiltrosTab);
            app.DilataoPanel.Title = 'Dilatação';
            app.BotaoDilatacao = uibutton(app.DilataoPanel, 'push');
            app.BotaoDilatacao.ButtonPushedFcn = createCallbackFcn(app, @ErosaoDilatacaoButtonPushed, true);
            app.BotaoDilatacao.Text = 'Adicionar';
            app.ElementoestruturanteDropDown_2Label = uilabel(app.DilataoPanel);
            app.ElementoestruturanteDropDown_2Label.Text = 'Elemento estruturante';
            app.ElementoDilatacaoDropDown = uidropdown(app.DilataoPanel);
            app.ElementoDilatacaoDropDown.Items = {'Diamante', 'Disco', 'Linha', 'Octágono', 'Retângulo', 'Quadrado'};
            app.ElementoDilatacaoDropDown.Value = 'Diamante';
            app.BotaoMoverPraCima = uibutton(app.FiltrosTab, 'push');
            app.BotaoMoverPraCima.ButtonPushedFcn = createCallbackFcn(app, @BotaoMoverPraCimaButtonPushed, true);
            app.BotaoMoverPraCima.Icon = 'ParaCima.png';
            app.BotaoMoverPraCima.Text = '';
            app.BotaoMoverPraBaixo = uibutton(app.FiltrosTab, 'push');
            app.BotaoMoverPraBaixo.ButtonPushedFcn = createCallbackFcn(app, @BotaoMoverPraBaixoButtonPushed, true);
            app.BotaoMoverPraBaixo.Icon = 'ParaBaixo.png';
            app.BotaoMoverPraBaixo.Text = '';
            app.BotaoDeletarFiltro = uibutton(app.FiltrosTab, 'push');
            app.BotaoDeletarFiltro.ButtonPushedFcn = createCallbackFcn(app, @BotaoDeletarFiltroButtonPushed, true);
            app.BotaoDeletarFiltro.Icon = 'Delete.png';
            app.BotaoDeletarFiltro.Text = '';
            app.EqualizaodohistogramaPanel = uipanel(app.FiltrosTab);
            app.EqualizaodohistogramaPanel.Title = 'Equalização do histograma';
            app.BotaoEqualizarHistograma = uibutton(app.EqualizaodohistogramaPanel, 'push');
            app.BotaoEqualizarHistograma.ButtonPushedFcn = createCallbackFcn(app, @BotaoEqualizarHistogramaValueChanged, true);
            app.BotaoEqualizarHistograma.Text = 'Adicionar';
            app.CorrecaoGama = uipanel(app.FiltrosTab);
            app.CorrecaoGama.Title = 'Correção Gamma';
            app.ValordogammaLabel = uilabel(app.CorrecaoGama);
            app.ValordogammaLabel.Text = 'Valor do gamma';
            app.ValorGamma = uieditfield(app.CorrecaoGama, 'numeric');
            app.ValorGamma.Value = 3;
            app.BotaoGamma = uibutton(app.CorrecaoGama, 'push');
            app.BotaoGamma.ButtonPushedFcn = createCallbackFcn(app, @BotaoGammaButtonPushed, true);
            app.BotaoGamma.Text = 'Adicionar';
            app.AplicarVideoCheckBox = uicheckbox(app.FiltrosTab);
            app.AplicarVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AplicarFiltroCheckBoxValueChanged, true);
            app.AplicarVideoCheckBox.Text = 'Aplicar no vídeo';
            app.AplicarReconstrucaoCheckBox = uicheckbox(app.FiltrosTab);
            app.AplicarReconstrucaoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AplicarFiltroCheckBoxValueChanged, true);
            app.AplicarReconstrucaoCheckBox.Text = 'Aplicar na reconstrução';
            app.LimiarizaoPanel = uipanel(app.FiltrosTab);
            app.LimiarizaoPanel.Title = 'Limiarização';
            app.AdicionarLimiar = uibutton(app.LimiarizaoPanel, 'push');
            app.AdicionarLimiar.ButtonPushedFcn = createCallbackFcn(app, @AdicionarMinimoValueChanged, true);
            app.AdicionarLimiar.Text = 'Adicionar Limiar';
            app.AdicionarIntervalo = uibutton(app.LimiarizaoPanel, 'push');
            app.AdicionarIntervalo.ButtonPushedFcn = createCallbackFcn(app, @AdicionarMinimoValueChanged, true);
            app.AdicionarIntervalo.Text = 'Adicionar intervalo';
            app.AdicionarAutomatico = uibutton(app.LimiarizaoPanel, 'push');
            app.AdicionarAutomatico.ButtonPushedFcn = createCallbackFcn(app, @AdicionarMinimoValueChanged, true);
            app.AdicionarAutomatico.Text = 'Automático';
            app.TSRTab = uitab(app.TabGroup);
            app.TSRTab.Title = 'TSR';
            app.GerarMatrizButton = uibutton(app.TSRTab, 'push');
            app.GerarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @Reconstruction, true);
            app.GerarMatrizButton.Enable = 'off';
            app.GerarMatrizButton.Text = 'Gerar matriz';
            app.UtilizeomtodolentoseovdeoLabel = uilabel(app.TSRTab);
            app.UtilizeomtodolentoseovdeoLabel.Text = 'Se o vídeo for muito grande, o computador poderá travar.';
            app.TamanhooriginalEditFieldLabel = uilabel(app.TSRTab);
            app.TamanhooriginalEditFieldLabel.Text = 'Tamanho original:';
            app.TamanhooriginalEditField = uieditfield(app.TSRTab, 'numeric');
            app.TamanhooriginalEditField.Editable = 'off';
            app.TamanhodamatrizEditFieldLabel = uilabel(app.TSRTab);
            app.TamanhodamatrizEditFieldLabel.Text = 'Tamanho da matriz:';
            app.TamanhodamatrizEditField = uieditfield(app.TSRTab, 'numeric');
            app.TamanhodamatrizEditField.Editable = 'off';
            app.CompactaodeEditFieldLabel = uilabel(app.TSRTab);
            app.CompactaodeEditFieldLabel.Text = 'Compactação de:';
            app.CompactacaoEditField = uieditfield(app.TSRTab, 'numeric');
            app.CompactacaoEditField.Editable = 'off';
            app.SalvarmatrizemEditFieldLabel = uilabel(app.TSRTab);
            app.SalvarmatrizemEditFieldLabel.Text = 'Salvar matriz em...:';
            app.SalvarmatrizEditField = uieditfield(app.TSRTab, 'text');
            app.Button = uibutton(app.TSRTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.MBLabel = uilabel(app.TSRTab);
            app.MBLabel.Text = 'MB';
            app.MBLabel_2 = uilabel(app.TSRTab);
            app.MBLabel_2.Text = 'MB';
            app.Label = uilabel(app.TSRTab);
            app.Label.Text = '%';
            app.MenorrEditFieldLabel = uilabel(app.TSRTab);
            app.MenorrEditFieldLabel.Text = 'Menor r²';
            app.MenorrEditField = uieditfield(app.TSRTab, 'numeric');
            app.MenorrEditField.Editable = 'off';
            app.MaiorrEditFieldLabel = uilabel(app.TSRTab);
            app.MaiorrEditFieldLabel.Text = 'Maior r²';
            app.MaiorrEditField = uieditfield(app.TSRTab, 'numeric');
            app.MaiorrEditField.Editable = 'off';
            app.VisualizarMatrizButton = uibutton(app.TSRTab, 'push');
            app.VisualizarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarMatrizButtonPushed, true);
            app.VisualizarMatrizButton.Text = 'Visualizar Matriz';
            app.PolinomioLabel2 = uilabel(app.TSRTab);
            app.PolinomioLabel2.Text = 'É ajustado polinômios de cada píxel ao longo do tempo na forma:';
            app.PolinomioLabel = uilabel(app.TSRTab);
            app.PolinomioLabel.Text = 'T = a1*x^6+a2*x^5+a3*x^4+a4*x^3+a5*x^2+a6*x+a7 ';
            app.GraudopolinmioEditFieldLabel = uilabel(app.TSRTab);
            app.GraudopolinmioEditFieldLabel.Text = 'Grau do polinômio:';
            app.GrauPolinomioEditField = uieditfield(app.TSRTab, 'numeric');
            app.GrauPolinomioEditField.Limits = [0 Inf];
            app.GrauPolinomioEditField.ValueChangedFcn = createCallbackFcn(app, @GrauPolinomioEditFieldValueChanged, true);
            app.GrauPolinomioEditField.Value = 6;
            app.FunodeajusteLabel = uilabel(app.TSRTab);
            app.FunodeajusteLabel.Text = 'Função de ajuste';
            app.FuncaodeajusteDropDown = uidropdown(app.TSRTab);
            app.FuncaodeajusteDropDown.Items = {'Polinômio', 'Polinômio Exponencial'};
            app.FuncaodeajusteDropDown.ValueChangedFcn = createCallbackFcn(app, @GrauPolinomioEditFieldValueChanged, true);
            app.FuncaodeajusteDropDown.Value = 'Polinômio';
            app.DividirLentoCheckBox = uicheckbox(app.TSRTab);
            app.DividirLentoCheckBox.Text = 'Dividir (Lento)';
            app.VisualizarreconstruoButton = uibutton(app.TSRTab, 'push');
            app.VisualizarreconstruoButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarreconstruoButtonPushed, true);
            app.VisualizarreconstruoButton.Enable = 'off';
            app.VisualizarreconstruoButton.Text = 'Visualizar reconstrução';
            app.DerivarButton = uibutton(app.TSRTab, 'push');
            app.DerivarButton.ButtonPushedFcn = createCallbackFcn(app, @DerivarButtonPushed, true);
            app.DerivarButton.Enable = 'off';
            app.DerivarButton.Text = 'Derivar';
            app.TempoEditFieldLabel = uilabel(app.TSRTab);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.TSRTab, 'numeric');
            app.TempoEditField.Limits = [0 Inf];
            app.TempoEditField.ValueChangedFcn = createCallbackFcn(app, @TempoEditFieldValueChanged, true);
            app.CarregarmatrizButton = uibutton(app.TSRTab, 'push');
            app.CarregarmatrizButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarmatrizButtonPushed, true);
            app.CarregarmatrizButton.Text = 'Carregar matriz';
            app.DerivadaAtualLabel = uilabel(app.TSRTab);
            app.DerivadaAtualLabel.Text = 'Derivada atual: 0';
            app.MatrizLabel = uilabel(app.TSRTab);
            app.MatrizLabel.Text = 'Matriz:';
            app.MatrizDropDown = uidropdown(app.TSRTab);
            app.MatrizDropDown.Items = {};
            app.MatrizDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrizDropDownValueChanged, true);
            app.MatrizDropDown.Value = {};
            app.FiltrarCheckBox = uicheckbox(app.TSRTab);
            app.FiltrarCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiltrarCheckBoxValueChanged, true);
            app.FiltrarCheckBox.Text = 'Filtrar (Lento)';
            app.TamanhodofiltroEditFieldLabel = uilabel(app.TSRTab);
            app.TamanhodofiltroEditFieldLabel.Text = 'Tamanho do filtro';
            app.TamanhodofiltroEditField = uieditfield(app.TSRTab, 'numeric');
            app.TamanhodofiltroEditField.Limits = [1 Inf];
            app.TamanhodofiltroEditField.Enable = 'off';
            app.TamanhodofiltroEditField.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = CameraTermica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end