classdef ModificarParametros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModificarparmetrosUIFigure      matlab.ui.Figure
        DistnciadoobjetoEditFieldLabel  matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        TemperaturarefletidaEditFieldLabel  matlab.ui.control.Label
        TemperaturaEditField            matlab.ui.control.NumericEditField
        EmissividadeEditFieldLabel      matlab.ui.control.Label
        EmissividadeEditField           matlab.ui.control.NumericEditField
        AplicarButton                   matlab.ui.control.Button
        CancelarButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp; % Description
        ThermalCamera;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Existe)
            app.MainApp = MainApp;
            if(Existe)
                app.ThermalCamera = app.MainApp.GetCamera();
                Parametros = app.ThermalCamera.ThermalImage.ThermalParameters;
                app.DistanciaEditField.Value = Parametros.Distance;
                app.TemperaturaEditField.Value = Parametros.ReflectedTemperature;
                app.EmissividadeEditField.Value = Parametros.Emissivity;
            end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            Distance = app.DistanciaEditField.Value;
            Temperature = app.TemperaturaEditField.Value;
            Emissivity = app.EmissividadeEditField.Value;
            app.MainApp.AplicarPropriedades(Distance, Temperature, Emissivity);
            delete(app);
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModificarparmetrosUIFigure = uifigure;
            app.ModificarparmetrosUIFigure.Name = 'Modificar parâmetros';
            app.DistnciadoobjetoEditFieldLabel = uilabel(app.ModificarparmetrosUIFigure);
            app.DistnciadoobjetoEditFieldLabel.Text = 'Distância do objeto';
            app.DistanciaEditField = uieditfield(app.ModificarparmetrosUIFigure, 'numeric');
            app.TemperaturarefletidaEditFieldLabel = uilabel(app.ModificarparmetrosUIFigure);
            app.TemperaturarefletidaEditFieldLabel.Text = 'Temperatura refletida';
            app.TemperaturaEditField = uieditfield(app.ModificarparmetrosUIFigure, 'numeric');
            app.EmissividadeEditFieldLabel = uilabel(app.ModificarparmetrosUIFigure);
            app.EmissividadeEditFieldLabel.Text = 'Emissividade';
            app.EmissividadeEditField = uieditfield(app.ModificarparmetrosUIFigure, 'numeric');
            app.EmissividadeEditField.Limits = [0 1];
            app.AplicarButton = uibutton(app.ModificarparmetrosUIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.CancelarButton = uibutton(app.ModificarparmetrosUIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ModificarParametros(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModificarparmetrosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModificarparmetrosUIFigure)
        end
    end
end