classdef SelecionarCamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CmerasdisponveisLabel    matlab.ui.control.Label
        CmerasdisponveisListBox  matlab.ui.control.ListBox
        SelecionarButton         matlab.ui.control.Button
        CancelarButton           matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp % Description
        ItemSelecionado
        CamerasDisponiveis
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AppPrincipal, CamerasDisponiveis)
            app.MainApp = AppPrincipal;
            app.CamerasDisponiveis = CamerasDisponiveis;
            app.CmerasdisponveisListBox.Items = cellstr(app.CamerasDisponiveis);
            app.CmerasdisponveisListBox.Value = app.CmerasdisponveisListBox.Items(1);
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: CmerasdisponveisListBox
        function CmerasdisponveisListBoxValueChanged(app, event)
            app.ItemSelecionado = app.CmerasdisponveisListBox.Value;
        end

        % Button pushed function: SelecionarButton
        function SelecionarButtonPushed(app, event)
            Index = find(contains(app.CamerasDisponiveis, app.CmerasdisponveisListBox.Value));
            app.MainApp.SetIndexCamera(Index);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CmerasdisponveisLabel = uilabel(app.UIFigure);
            app.CmerasdisponveisLabel.Text = 'Câmeras disponíveis:';
            app.CmerasdisponveisListBox = uilistbox(app.UIFigure);
            app.CmerasdisponveisListBox.Items = {};
            app.CmerasdisponveisListBox.ValueChangedFcn = createCallbackFcn(app, @CmerasdisponveisListBoxValueChanged, true);
            app.CmerasdisponveisListBox.Value = {};
            app.SelecionarButton = uibutton(app.UIFigure, 'push');
            app.SelecionarButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarButtonPushed, true);
            app.SelecionarButton.Text = 'Selecionar';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SelecionarCamera(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end